classdef thermal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ToolGeometryTab                 matlab.ui.container.Tab
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        BevelledToolGeometryCheckBox    matlab.ui.control.CheckBox
        ToolContactTempLabel            matlab.ui.control.Label
        PoissonsRatioLabel              matlab.ui.control.Label
        YoungsModulosGPaLabel           matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        ToolContactTempEditField        matlab.ui.control.NumericEditField
        ToolContactTempEditFieldLabel   matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        MaterialDropDownLabel           matlab.ui.control.Label
        MaterialPropertiesLabel         matlab.ui.control.Label
        ToolGeometryLabel               matlab.ui.control.Label
        LoadBevelledToolButton          matlab.ui.control.Button
        ThicknessmEditField             matlab.ui.control.NumericEditField
        ThicknessmEditFieldLabel        matlab.ui.control.Label
        HeightmEditField                matlab.ui.control.NumericEditField
        HeightmEditFieldLabel           matlab.ui.control.Label
        BottomWidthmEditField           matlab.ui.control.NumericEditField
        BottomWidthmEditFieldLabel      matlab.ui.control.Label
        TopWidthmEditField              matlab.ui.control.NumericEditField
        TopWidthmEditFieldLabel         matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        MeshGeneratorTab                matlab.ui.container.Tab
        LoadANSYSMeshButton             matlab.ui.control.Button
        ResetButton_3                   matlab.ui.control.Button
        ParametersalongtheYaxisLabel    matlab.ui.control.Label
        ParametersalongtheXaxiasLabel   matlab.ui.control.Label
        ApplyButton_2                   matlab.ui.control.Button
        DivisionsintheYEditField        matlab.ui.control.NumericEditField
        DivisionsintheYEditFieldLabel   matlab.ui.control.Label
        DivisionsintherestEditField     matlab.ui.control.NumericEditField
        DivisionsintherestEditFieldLabel  matlab.ui.control.Label
        DivisionsintherefinedzoneEditField  matlab.ui.control.NumericEditField
        DivisionsintherefinedzoneEditFieldLabel  matlab.ui.control.Label
        RefinedZonemEditField           matlab.ui.control.NumericEditField
        RefinedZonemEditFieldLabel      matlab.ui.control.Label
        MeshGeneratorLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        LoadsandConstraintsTab          matlab.ui.container.Tab
        ResetButton_2                   matlab.ui.control.Button
        ForceContactLengthmEditField    matlab.ui.control.NumericEditField
        ForceContactLengthmEditFieldLabel  matlab.ui.control.Label
        ApplyButton_3                   matlab.ui.control.Button
        TopHalfRightEdgeCheckBox        matlab.ui.control.CheckBox
        RightChamferCheckBox            matlab.ui.control.CheckBox
        BottomEdgeCheckBox              matlab.ui.control.CheckBox
        CuttingTangentialForceNEditField  matlab.ui.control.NumericEditField
        CuttingTangentialForceNEditFieldLabel  matlab.ui.control.Label
        AxialFeedForceNEditField        matlab.ui.control.NumericEditField
        AxialFeedForceNEditFieldLabel   matlab.ui.control.Label
        BoundaryConstraintsLabel        matlab.ui.control.Label
        ApplyLoadsBoundaryConditionsLabel  matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        ThermalConstraintsTab           matlab.ui.container.Tab
        ToolTipContactLengthmEditField  matlab.ui.control.NumericEditField
        ToolTipContactLengthmEditFieldLabel  matlab.ui.control.Label
        ResetButton_4                   matlab.ui.control.Button
        ApplyButton_4                   matlab.ui.control.Button
        ThermalConstraintParametersLabel  matlab.ui.control.Label
        BoundaryConstraintsLabel_2      matlab.ui.control.Label
        TopRightEdgeCheckBox            matlab.ui.control.CheckBox
        RightChamferCheckBox_2          matlab.ui.control.CheckBox
        BottomEdgeCheckBox_2            matlab.ui.control.CheckBox
        ToolAmbientTempEditField        matlab.ui.control.NumericEditField
        ToolAmbientTempEditFieldLabel   matlab.ui.control.Label
        ToolContactTempEditField_2      matlab.ui.control.NumericEditField
        ToolContactTempEditField_2Label  matlab.ui.control.Label
        ToolTemperaturesLabel           matlab.ui.control.Label
        UIAxes6                         matlab.ui.control.UIAxes
        SolveTab                        matlab.ui.container.Tab
        PlotButton_2                    matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        ScaleEditField                  matlab.ui.control.NumericEditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        ContoursLabel                   matlab.ui.control.Label
        SolveButton                     matlab.ui.control.Button
        PatchDropDown                   matlab.ui.control.DropDown
        PatchDropDownLabel              matlab.ui.control.Label
        IsolinesDropDown                matlab.ui.control.DropDown
        IsolinesDropDownLabel           matlab.ui.control.Label
        UIAxes5_2                       matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description

        n_elements % Number of elements generated in the mesh
        n_nodes %Number of nodes in the generated mesh

        E %Youngs modulus of the selected material
        A %Area of the element
        
        ncon %Connection between nodes

        X %Coordiantes of nodes 
        Y

        NDU %Nodal degree of freedom   
        dzero %Setting some nodes to zero degrees of freedom (no displacment)

        F %Force vector acting on the nodes 
        v %Poissons ratio
        t %Thickeness of material

        T              % Nodal temperature result
        k_tool         % Thermal conductivity of main tool material
        k_BUE          % Thermal conductivity of BUE / stagnation zone
        Tambient       % Ambient temperature
        thermalNodes   % Thermal fixed temperature nodes
        thermalValues  % Prescribed temperature values

        % Initiate Matrices
        KE %Elemntal stiffness matrix
        K  %Global sitffness matrix of the piece
    
        KM %Duplicating 

        
        Wt %Top width of the isoceles trapezium
        Wb %Bottom width of the isoceles trapezium
        H %Height of isoceles trapezium

        U %Displacemnt matrix

        Ex %Strain in the X
        Ey %Strain in the Y
        Gxy %Shearing strain 

        Sx %Stress in the X
        Sy % Stress in the Y
        Sxy % Shearing stress

        Ux %Displacment in the X
        Uy %Displacement in the Y
        Umag %magnitude of the total displacement

        yield_strength %yeild strength of the material (optional)
        tensile_strength %Ultimate tensile strength
        compressive_strength %Compressive strength
        Ductile %How ductile is material
        Xb %X coordinates of the tool geometry boundary
        Yb %Y coordiantes of the tool geometry boundary
        Sim_strength
        ToolApp
        MeshAppp
        

        L_ref% refined length (from left)
        nx_ref% divisions in refined zone
        nx_coarse% divisions in rest
        ny%Divisions in the Y direction

        resetmesh %Keep track of the reset mesh state
        ANSYSmesh %keep track of the laoding ansys mesh

        Fc %Cutting force
        Ft %Thrust force
        Tc %Force Contact Length
        scale %Scale of deformity

        contactTemp

        check

        X_body
        Y_body
        X_tri
        Y_tri

        material

        botedge
        rightcham
        topredge

        bevelled

        clamp


        Xdef 
        Ydef
        Xorig
        Yorig

        normalMesh
        

        clampedNodes
        contactNodes
        

        thermalContact

        Tbotedge
        Trightcham
        Ttopredge

        fixedNodes
        fixedValues

        thermalTipNodes
    end
    
    methods (Access = private)
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %{
            app.TopWidthmEditField.Enable = 'off';
            app.BottomWidthmEditField.Enable = 'off';
            app.HeightmEditField.Enable = 'off';
            app.ThicknessmEditField.Enable = 'off';
            %}
            app.LoadBevelledToolButton.Enable = 'off';
            app.normalMesh = false;
            app.bevelled = false;
            app.ANSYSmesh = false;
            app.check = false;
            
            app.X = [];
            app.Y = [];
            app.ncon = [];
            app.material = [];
            app.F = [];
            app.dzero = [];
            app.NDU = [];
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.StatusLamp.Color = 'red';
            app.Xb = [];
            app.Yb = [];


            app.E = 0;
            app.v = 0;
            app.contactTemp = 0;
            app.t = 0;
                
            cla(app.UIAxes3)
            plot(app.UIAxes3, app.Xb, app.Yb);
            axis(app.UIAxes3, 'equal');
            
            app.check = false;
            app.bevelled = false;
            app.normalMesh = false;
            app.ANSYSmesh = false;
            app.YoungsModulosGPaLabel.Text = sprintf('Youngs Modulus (GPa): %.2f', app.E);
            app.PoissonsRatioLabel.Text = sprintf('Poissons Ratio: %.2f', app.v);
            app.ToolContactTempLabel.Text = sprintf('Tool Contact Temperature (C): %.2f', app.contactTemp);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % =========================================================
            % GEOMETRY SELECTION
            % =========================================================
            % Use the checkbox/app.bevelled flag directly.
            % Do not rely only on app.check, because if app.check is false
            % the app accidentally creates a zero-size custom tool.
            
            if app.BevelledToolGeometryCheckBox.Value == true || app.bevelled == true
            
                app.bevelled = true;
                app.normalMesh = false;
                app.ANSYSmesh = false;
                app.check = true;
            
                % If bevelled geometry has not been loaded yet, create it here.
                if isempty(app.X_body) || isempty(app.Y_body) || isempty(app.X_tri) || isempty(app.Y_tri)
            
                    app.X_body = [
                        0.0002533
                        0.012
                        0.0112
                        0.00078
                        0.0000123
                        0.0002533
                    ];
            
                    app.Y_body = [
                         0
                         0
                        -0.004
                        -0.004
                        -0.0002121
                         0
                    ];
            
                    app.X_tri = [
                        0
                        0.0002533
                        0.0000123
                        0
                    ];
            
                    app.Y_tri = [
                         0
                         0
                        -0.0002121
                         0
                    ];
                end
            
                cla(app.UIAxes3)
                plot(app.UIAxes3, app.X_body, app.Y_body, 'b-');
                hold(app.UIAxes3,'on')
                plot(app.UIAxes3, app.X_tri, app.Y_tri, 'r-');
                axis(app.UIAxes3, 'equal');
                grid(app.UIAxes3, 'on');
                hold(app.UIAxes3,'off')
            
                app.t = 0.001;
                app.ThicknessmEditField.Value = app.t;
                app.StatusLamp.Color = 'green';
            
            else
            
                app.Wt = app.TopWidthmEditField.Value;
                app.Wb = app.BottomWidthmEditField.Value;
                app.H  = app.HeightmEditField.Value;
            
                [app.Xb, app.Yb] = CreateCustomTool(app.Wt, app.Wb, app.H);
            
                app.normalMesh = true;
                app.bevelled = false;
                app.ANSYSmesh = false;
                app.check = false;
            
                cla(app.UIAxes3)
                plot(app.UIAxes3, app.Xb, app.Yb);
                axis(app.UIAxes3, 'equal');
                grid(app.UIAxes3, 'on');
            
                app.t = app.ThicknessmEditField.Value;
            
            end
            
            if app.bevelled == true
                app.t = 0.001;   % bevelled tool default thickness
            else
                app.t = app.ThicknessmEditField.Value;
            end

            app.contactTemp = app.ToolContactTempEditField.Value;

            material = app.MaterialDropDown.Value;
            switch material

                case 'Tungsten Carbide'
                    app.E = 640e9;
                    %app.E = 510e9;
                    app.v = 0.2;
                    %app.v = 0.3;
            
                    % Initial assumed thermal conductivity for tungsten carbide
                    app.k_tool = 84;      % W/(m.K)
            
                case 'M2 High Speed Steel'
                    app.E = 200e9;
                    app.v = 0.29;
            
                    % Initial assumed thermal conductivity for M2 HSS
                    app.k_tool = 25;      % W/(m.K)
            end
            
            % BUE/stagnation zone assumed steel-like for now
            app.k_BUE = 45;              % W/(m.K)
            
            % Ambient temperature for outer boundaries
            %app.Tambient = 150;           % deg C

            disp('***Youngs Modulos***')
            disp(app.E);
            disp('***Poissons Ratio***')
            disp(app.v);

            disp('Boundary coordinates for the tool geometry X, Y')
            disp(app.Xb);
            disp(app.Yb);

            app.YoungsModulosGPaLabel.Text = sprintf('Youngs Modulus (GPa): %.2f', (app.E/10^9));
            app.PoissonsRatioLabel.Text = sprintf('Poissons Ratio: %.2f', app.v);
            app.ToolContactTempLabel.Text = sprintf('Tool Contact Temperature (C): %.2f', app.contactTemp);
        end

        % Value changed function: BevelledToolGeometryCheckBox
        function BevelledToolGeometryCheckBoxValueChanged(app, event)
            value = app.BevelledToolGeometryCheckBox.Value;
            if value == 1
                %User can now laod Bevelled tool geometry
                app.LoadBevelledToolButton.Enable = 'on';

                %Grey out option to input values for custom geometry
                app.TopWidthmEditField.Enable = 'off';
                app.BottomWidthmEditField.Enable = 'off';
                app.HeightmEditField.Enable = 'off';
                app.ThicknessmEditField.Enable = 'off';
            
            else
                app.LoadBevelledToolButton.Enable = 'off';
                %Return option tool input custom tool parameters
                app.TopWidthmEditField.Enable = 'on';
                app.BottomWidthmEditField.Enable = 'on';
                app.HeightmEditField.Enable = 'on';
                app.ThicknessmEditField.Enable = 'on';

                app.StatusLamp.Color = 'red';
            end
            
        end

        % Button pushed function: LoadBevelledToolButton
        function LoadBevelledToolButtonPushed(app, event)
            app.StatusLamp.Color = 'green';
            app.t = 0.001;
            app.ThicknessmEditField.Value = app.t;
            app.check = true;
            app.bevelled = true;
            app.normalMesh = false;
            app.ANSYSmesh = false;
%% =========================
%  MAIN TOOL BODY
%  Counter-clockwise ordering
%  Starting from top-left
%  =========================
app.X_body = [
    0.0002533
    0.012
    0.0112
    0.00078
    0.0000123
    0.0002533
];
app.Y_body = [
     0
     0
    -0.004
    -0.004
    -0.0002121
     0
];
%% =========================
%  SMALL RED TRIANGLE
%  Counter-clockwise ordering
%  =========================
app.X_tri = [
    0
    0.0002533
    0.0000123
    0
];
app.Y_tri = [
     0
     0
    -0.0002121
     0
];
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
        cla(app.UIAxes)

            if app.bevelled == true

                nx = 10;
                
                
                [app.X, app.Y, app.ncon, app.material, app.n_nodes, app.n_elements] = GenerateBevelToolMesh( ...
                    app.X_body, app.Y_body, ...
                    app.X_tri, app.Y_tri, ...
                    nx);
                

                % %===========TESTING NEW MESH ZONE=======================
                % app.L_ref = app.RefinedZonemEditField.Value;
                % app.nx_ref = app.DivisionsintherefinedzoneEditField.Value;
                % app.nx_coarse = app.DivisionsintherestEditField.Value;
                % app.ny = app.DivisionsintheYEditField.Value;
                % 
                % [app.X, app.Y, app.ncon, app.material, app.n_nodes, app.n_elements] = GenerateBevelToolMesh2(...
                %     app.X_body, app.Y_body, app.X_tri, app.Y_tri, app.L_ref, app.nx_ref, app.nx_coarse, app.ny);

                %====================================================
                patch(app.UIAxes, ...
                    'Faces', app.ncon, ...
                    'Vertices', [app.X app.Y], ...
                    'FaceColor', 'none', ...
                    'EdgeColor', 'k');

                axis(app.UIAxes, 'equal');
                grid(app.UIAxes, 'on');

            elseif app.normalMesh == true

                app.L_ref = app.RefinedZonemEditField.Value;
                app.nx_ref = app.DivisionsintherefinedzoneEditField.Value;
                app.nx_coarse = app.DivisionsintherestEditField.Value;
                app.ny = app.DivisionsintheYEditField.Value;

                [app.X, app.Y, app.ncon, app.n_nodes, app.n_elements] = GenerateCustomToolMesh( ...
                    app.Xb, app.Yb, ...
                    app.L_ref, app.nx_ref, app.nx_coarse, app.ny);

                app.material = ones(app.n_elements,1);

                patch(app.UIAxes, ...
                    'Faces', app.ncon, ...
                    'Vertices', [app.X app.Y], ...
                    'FaceColor', 'none', ...
                    'EdgeColor', 'k');

                axis(app.UIAxes, 'equal');
                grid(app.UIAxes, 'on');

            else

                error('No valid geometry/mesh type selected.');

            end   
        end

        % Button pushed function: ApplyButton_3
        function ApplyButton_3Pushed(app, event)
            app.Ft = app.AxialFeedForceNEditField.Value;
            app.Fc = app.CuttingTangentialForceNEditField.Value;
            app.Tc = app.ForceContactLengthmEditField.Value;
            
            valueB = app.BottomEdgeCheckBox.Value;
            if valueB == 1

                app.botedge=true;

            else

                app.botedge=false;

            end


            valueR = app.RightChamferCheckBox.Value;
            if valueR == 1

                app.rightcham=true;

            else

                app.rightcham=false;

            end


            valueT = app.TopHalfRightEdgeCheckBox.Value;
            if valueT == 1

                app.topredge=true;

            else

                app.topredge=false;

            end

            if isempty(app.X) || isempty(app.Y) || isempty(app.ncon)
                error('Mesh has not been generated. Go to Mesh Generator and click Apply first.');
            end
            [app.F, app.NDU, app.dzero,app.clampedNodes, app.contactNodes] = ApplyLoadsBoundary(app.Ft, app.Fc, app.Tc, app.botedge, app.rightcham,app.topredge,app.n_nodes,app.X,app.Y,app.Xb,...
                app.Yb,app.X_body,app.Y_body,app.X_tri,app.Y_tri,app.bevelled, app.ANSYSmesh, app.normalMesh);

cla(app.UIAxes4)

% Plot all nodes
if app.bevelled == true
            plot(app.UIAxes4, app.X_body, app.Y_body);
            hold(app.UIAxes4,'on')
            plot(app.UIAxes4, app.X_tri, app.Y_tri)

            % Plot clamped nodes
            scatter(app.UIAxes4, ...
                app.X(app.clampedNodes), ...
                app.Y(app.clampedNodes), ...
                70, 'filled', 'r')
            
            % Plot contact nodes
            scatter(app.UIAxes4, ...
                app.X(app.contactNodes), ...
                app.Y(app.contactNodes), ...
                70, 'filled', 'b')
            axis(app.UIAxes4, 'equal');
            hold(app.UIAxes4,'off')
end
if app.normalMesh == true
plot(app.UIAxes4, app.Xb, app.Yb)
hold(app.UIAxes4, 'on')

% Plot clamped nodes
scatter(app.UIAxes4, ...
    app.X(app.clampedNodes), ...
    app.Y(app.clampedNodes), ...
    70, 'filled', 'r')

% Plot contact nodes
scatter(app.UIAxes4, ...
    app.X(app.contactNodes), ...
    app.Y(app.contactNodes), ...
    70, 'filled', 'b')

hold(app.UIAxes4, 'off')

axis(app.UIAxes4, 'equal')
grid(app.UIAxes4, 'on')
end

if app.ANSYSmesh == true
plot(app.UIAxes4, app.X, app.Y)
hold(app.UIAxes4, 'on')

% Plot clamped nodes
scatter(app.UIAxes4, ...
    app.X(app.clampedNodes), ...
    app.Y(app.clampedNodes), ...
    70, 'filled', 'r')

% Plot contact nodes
scatter(app.UIAxes4, ...
    app.X(app.contactNodes), ...
    app.Y(app.contactNodes), ...
    70, 'filled', 'b')

hold(app.UIAxes4, 'off')

axis(app.UIAxes4, 'equal')
grid(app.UIAxes4, 'on')

end


        end

        % Value changed function: BottomEdgeCheckBox
        function BottomEdgeCheckBoxValueChanged(app, event)
            valueB = app.BottomEdgeCheckBox.Value;

            if valueB == 1

                app.botedge=true;

            else

                app.botedge=false;

            end
            
        end

        % Value changed function: RightChamferCheckBox
        function RightChamferCheckBoxValueChanged(app, event)
            valueR = app.RightChamferCheckBox.Value;
            if valueR == 1

                app.rightcham=true;

            else

                app.rightcham=false;

            end
            
        end

        % Value changed function: TopHalfRightEdgeCheckBox
        function TopHalfRightEdgeCheckBoxValueChanged(app, event)
            valueT = app.TopHalfRightEdgeCheckBox.Value;

            if valueT == 1

                app.topredge=true;

            else

                app.topredge=false;

            end
            
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.F = [];
            app.NDU = [];
            app.dzero = [];
            app.clampedNodes = [];
            app.contactNodes = [];
            cla(app.UIAxes4)


        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.X = [];
            app.Y = [];
            app.ncon = [];
            app.n_nodes = [];
            app.n_elements = [];
            cla(app.UIAxes);
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)

            if isempty(app.X) || isempty(app.Y) || isempty(app.ncon)
                error('No mesh available. Generate the mesh first.');
            end
            
            if isempty(app.F) || isempty(app.dzero) || isempty(app.NDU)
                error('Loads and boundary conditions have not been applied. Go to Loads and Constraints and click Apply first.');
            end
            
            if isempty(app.material)
                app.material = ones(app.n_elements,1);
            end
            
            [app.U, app.Ux, app.Uy, app.Umag, ...
             app.Ex, app.Ey, app.Gxy, ...
             app.Sx, app.Sy, app.Sxy] = ...
             SolveFEM( ...
                app.n_nodes, ...
                app.n_elements, ...
                app.ncon, ...
                app.X, ...
                app.Y, ...
                app.E, ...
                app.v, ...
                app.t, ...
                app.F, ...
                app.dzero, ...
                app.NDU, ...
                app.material);
                            
            
               [app.Xdef, app.Ydef, app.Xorig, app.Yorig] = ...
                display_structure( ...
                    app.n_elements,...
                    app.ncon,...
                    app.X,...
                    app.Y,...
                    app.U,...
                    app.ScaleEditField.Value);
            
            
               cla(app.UIAxes5)
               cla(app.UIAxes5_2)
            hold(app.UIAxes5,'on')
            
            for i = 1:app.n_elements
            
                %{
                % Original mesh
                plot(app.UIAxes, ...
                    Xorig(i,:), ...
                    Yorig(i,:), ...
                    'k-')
                %}
                % Deformed mesh
                plot(app.UIAxes5, ...
                    app.Xdef(i,:), ...
                    app.Ydef(i,:), ...
                    'r-','LineWidth',1.5)
            
            end
            
            axis(app.UIAxes5,'equal')
            grid(app.UIAxes5,'on')
            
            hold(app.UIAxes5,'off')

            % =========================================================
            % THERMAL ANALYSIS
            % =========================================================
            % Thermal model:
            % - Contact nodes are prescribed at tool contact temperature
            % - Remaining external boundary nodes are prescribed at ambient
            % - Internal nodes are solved by steady-state conduction
            
            %{
            [boundaryNodes, boundaryEdges] = findBoundaryNodes(app.ncon);
            
            % Start with all boundary nodes at ambient temperature
            fixedNodes = boundaryNodes(:);
            fixedValues = app.Tambient * ones(length(fixedNodes),1);
            
            % Contact nodes are hot boundary nodes
            contactTemp = app.contactTemp;
            
            % If contact temperature is not set, use a clear test value
            if contactTemp == 0
                contactTemp = 500;
                app.contactTemp = contactTemp;
                app.ToolContactTempEditField.Value = contactTemp;
            end
            
            % Override boundary values at contact nodes
            for i = 1:length(app.contactNodes)
            
                cNode = app.contactNodes(i);
            
                idx = find(fixedNodes == cNode, 1);
            
                if isempty(idx)
                    fixedNodes = [fixedNodes; cNode];
                    fixedValues = [fixedValues; contactTemp];
                else
                    fixedValues(idx) = contactTemp;
                end
            
            end
            %}
            
            app.thermalNodes = app.fixedNodes;
            app.thermalValues = app.fixedValues;
            
            app.T = SolveThermal( ...
                app.n_nodes, ...
                app.n_elements, ...
                app.ncon, ...
                app.X, ...
                app.Y, ...
                app.t, ...
                app.material, ...
                app.k_tool, ...
                app.k_BUE, ...
                app.thermalNodes, ...
                app.thermalValues);
            
            plotThermalPatch( ...
                app.UIAxes5_2, ...
                app.X, ...
                app.Y, ...
                app.ncon, ...
                app.T);
            
            fprintf('Thermal analysis complete.\n');
            fprintf('Minimum temperature = %.3f C\n', min(app.T));
            fprintf('Maximum temperature = %.3f C\n', max(app.T));            

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %{
            resultName = app.PatchDropDown.Value;
            

            switch resultName

                case 'Sx'
                cla(app.UIAxes5_2)
                hold(app.UIAxes5_2,'off')
                patch(app.UIAxes5_2, ...
                    'Faces', app.ncon, ...
                    'Vertices', [app.X app.Y], ...
                    'FaceVertexCData', app.Sx, ...
                    'FaceColor', 'flat', ...
                    'EdgeColor', [0.3 0.3 0.3]);
                
                axis(app.UIAxes5_2,'equal')
                
                colormap(app.UIAxes5_2,'jet')
                
                colorbar(app.UIAxes5_2)
                
                title(app.UIAxes5_2,'Stress Contour \sigma_x')
                
                xlabel(app.UIAxes5_2,'X')
                ylabel(app.UIAxes5_2,'Y')

                case 'Sy'
                cla(app.UIAxes5_2)
                hold(app.UIAxes5_2,'off')
                patch(app.UIAxes5_2, ...
                    'Faces', app.ncon, ...
                    'Vertices', [app.X app.Y], ...
                    'FaceVertexCData', app.Sy, ...
                    'FaceColor', 'flat', ...
                    'EdgeColor', [0.3 0.3 0.3]);
                
                axis(app.UIAxes5_2,'equal')
                
                colormap(app.UIAxes5_2,'jet')
                
                colorbar(app.UIAxes5_2)
                
                title(app.UIAxes5_2,'Stress Contour \sigma_y')
                
                xlabel(app.UIAxes5_2,'X')
                ylabel(app.UIAxes5_2,'Y')


                case 'Sxy'
                cla(app.UIAxes5_2)
                hold(app.UIAxes5_2,'off')
                patch(app.UIAxes5_2, ...
                    'Faces', app.ncon, ...
                    'Vertices', [app.X app.Y], ...
                    'FaceVertexCData', app.Sxy, ...
                    'FaceColor', 'flat', ...
                    'EdgeColor', [0.3 0.3 0.3]);
                
                axis(app.UIAxes5_2,'equal')
                
                colormap(app.UIAxes5_2,'jet')
                
                colorbar(app.UIAxes5_2)
                
                title(app.UIAxes5_2,'Stress Contour \sigma_xy')
                
                xlabel(app.UIAxes5_2,'X')
                ylabel(app.UIAxes5_2,'Y')

                case 'Ux'
                cla(app.UIAxes5_2)
                hold(app.UIAxes5_2,'off')
                patch(app.UIAxes5_2, ...
                    'Faces', app.ncon, ...
                    'Vertices', [app.X app.Y], ...
                    'FaceVertexCData', app.Ux, ...
                    'FaceColor', 'flat', ...
                    'EdgeColor', [0.3 0.3 0.3]);
                
                axis(app.UIAxes5_2,'equal')
                
                colormap(app.UIAxes5_2,'jet')
                
                colorbar(app.UIAxes5_2)
                
                title(app.UIAxes5_2,'Displacement \U_x')
                
                xlabel(app.UIAxes5_2,'X')
                ylabel(app.UIAxes5_2,'Y')
                case 'Uy'
                cla(app.UIAxes5_2)
                hold(app.UIAxes5_2,'off')
                patch(app.UIAxes5_2, ...
                    'Faces', app.ncon, ...
                    'Vertices', [app.X app.Y], ...
                    'FaceVertexCData', app.Uy, ...
                    'FaceColor', 'flat', ...
                    'EdgeColor', [0.3 0.3 0.3]);
                
                axis(app.UIAxes5_2,'equal')
                
                colormap(app.UIAxes5_2,'jet')
                
                colorbar(app.UIAxes5_2)
                
                title(app.UIAxes5_2,'Displacement \U_y')
                
                xlabel(app.UIAxes5_2,'X')
                ylabel(app.UIAxes5_2,'Y')
                case 'Uxy'
                cla(app.UIAxes5_2)
                hold(app.UIAxes5_2,'off')
                patch(app.UIAxes5_2, ...
                    'Faces', app.ncon, ...
                    'Vertices', [app.X app.Y], ...
                    'FaceVertexCData', app.Umag, ...
                    'FaceColor', 'flat', ...
                    'EdgeColor', [0.3 0.3 0.3]);
                
                axis(app.UIAxes5_2,'equal')
                
                colormap(app.UIAxes5_2,'jet')
                
                colorbar(app.UIAxes5_2)
                
                title(app.UIAxes5_2,'Displacement \|U|')
                
                xlabel(app.UIAxes5_2,'X')
                ylabel(app.UIAxes5_2,'Y')

                case 'Ex'
                cla(app.UIAxes5_2)
                hold(app.UIAxes5_2,'off')
                patch(app.UIAxes5_2, ...
                    'Faces', app.ncon, ...
                    'Vertices', [app.X app.Y], ...
                    'FaceVertexCData', app.Ex, ...
                    'FaceColor', 'flat', ...
                    'EdgeColor', [0.3 0.3 0.3]);
                
                axis(app.UIAxes5_2,'equal')
                
                colormap(app.UIAxes5_2,'jet')
                
                colorbar(app.UIAxes5_2)
                
                title(app.UIAxes5_2,'Strain Contour \epsilon_x')
                
                xlabel(app.UIAxes5_2,'X')
                ylabel(app.UIAxes5_2,'Y')

                case 'Ey'
                cla(app.UIAxes5_2)
                hold(app.UIAxes5_2,'off')
                patch(app.UIAxes5_2, ...
                    'Faces', app.ncon, ...
                    'Vertices', [app.X app.Y], ...
                    'FaceVertexCData', app.Ey, ...
                    'FaceColor', 'flat', ...
                    'EdgeColor', [0.3 0.3 0.3]);
                
                axis(app.UIAxes5_2,'equal')
                
                colormap(app.UIAxes5_2,'jet')
                
                colorbar(app.UIAxes5_2)
                
                title(app.UIAxes5_2,'Strain Contour \epsilon_y')
                
                xlabel(app.UIAxes5_2,'X')
                ylabel(app.UIAxes5_2,'Y')

                case 'Exy'    
                cla(app.UIAxes5_2)
                hold(app.UIAxes5_2,'off')
                patch(app.UIAxes5_2, ...
                    'Faces', app.ncon, ...
                    'Vertices', [app.X app.Y], ...
                    'FaceVertexCData', app.Gxy, ...
                    'FaceColor', 'flat', ...
                    'EdgeColor', [0.3 0.3 0.3]);
                
                axis(app.UIAxes5_2,'equal')
                
                colormap(app.UIAxes5_2,'jet')
                
                colorbar(app.UIAxes5_2)
                
                title(app.UIAxes5_2,'Strain Contour \epsilon_xy')
                
                xlabel(app.UIAxes5_2,'X')
                ylabel(app.UIAxes5_2,'Y')
            %}
            % =========================================================
            % RESET AXES COMPLETELY
            % =========================================================
            ax = app.UIAxes5_2;
            
            cla(ax,'reset')
            hold(ax,'off')
            
            % delete old colorbars
            delete(findall(app.UIFigure,'Type','ColorBar'))
            
            % =========================================================
            % GET DROPDOWN VALUE
            % =========================================================
            resultName = app.PatchDropDown.Value;
            
            % =========================================================
            % SELECT DATA + PLOTTING MODE
            % =========================================================
            switch resultName

            % -----------------------------------------------------
            % ELEMENT-WISE STRESSES AND STRAINS
            % -----------------------------------------------------
            case 'Sx'
        
                fieldData = averageElementToNodes(app.ncon, app.n_nodes, app.Sx);
                faceMode = 'interp';
                plotTitle = 'Stress Contour \sigma_x';
            
            case 'Sy'
            
                fieldData = averageElementToNodes(app.ncon, app.n_nodes, app.Sy);
                faceMode = 'interp';
                plotTitle = 'Stress Contour \sigma_y';
            
            case 'Sxy'
            
                fieldData = averageElementToNodes(app.ncon, app.n_nodes, app.Sxy);
                faceMode = 'interp';
                plotTitle = 'Stress Contour \sigma_{xy}';
            
            case 'Ex'
            
                fieldData = averageElementToNodes(app.ncon, app.n_nodes, app.Ex);
                faceMode = 'interp';
                plotTitle = 'Strain Contour \epsilon_x';
            
            case 'Ey'
            
                fieldData = averageElementToNodes(app.ncon, app.n_nodes, app.Ey);
                faceMode = 'interp';
                plotTitle = 'Strain Contour \epsilon_y';
            
            case 'Exy'
            
                fieldData = averageElementToNodes(app.ncon, app.n_nodes, app.Gxy);
                faceMode = 'interp';
                plotTitle = 'Strain Contour \epsilon_{xy}';
        
            % -----------------------------------------------------
            % NODAL DISPLACEMENTS
            % -----------------------------------------------------
            case 'Ux'
        
                fieldData = app.Ux;
                faceMode = 'flat';
                plotTitle = 'Displacement U_x';
        
            case 'Uy'
        
                fieldData = app.Uy;
                faceMode = 'interp';
                plotTitle = 'Displacement U_y';
        
            case 'Uxy'
        
                fieldData = app.Umag;
                faceMode = 'interp';
                plotTitle = 'Displacement |U|';

            case 'Temperature'
            
                if isempty(app.T)
                    error('Temperature field not available. Press Solve first.');
                end
            
                fieldData = app.T;
                faceMode = 'interp';
                plotTitle = 'Temperature Distribution';            
        
            otherwise
        
                error('Unknown dropdown selection')
        
        end

% =========================================================
% DRAW PATCH CONTOUR
% =========================================================
patch(ax, ...
    'Faces', app.ncon, ...
    'Vertices', [app.X app.Y], ...
    'FaceVertexCData', fieldData, ...
    'FaceColor', faceMode, ...
    'EdgeColor', 'none');

axis(ax,'equal')
grid(ax,'on')

xlim(ax, [min(app.X) max(app.X)]);
ylim(ax, [min(app.Y) max(app.Y)]);

colormap(ax,'jet')

cb = colorbar(ax);

if isempty(app.X) || isempty(app.Y) || isempty(app.ncon)
    error('No mesh available. Generate the mesh first.');
end

if isempty(app.U) || isempty(app.Sx)
    error('No FEM results available. Press Solve first.');
end

if strcmp(resultName,'Temperature')
    cb.Label.String = 'Temperature (°C)';
    colormap(ax,'jet')
else
    cb.Label.String = resultName;
    colormap(ax,'jet')
end

title(ax, plotTitle)

xlabel(ax,'X')
ylabel(ax,'Y')


        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            cla(app.UIAxes5_2)
            data.X = app.X;
            data.Y = app.Y;
            
            data.ncon = app.ncon;
            data.n_nodes = app.n_nodes;
            
            data.Ux = app.Ux;
            data.Uy = app.Uy;
            data.Umag = app.Umag;
            
            data.Ex = app.Ex;
            data.Ey = app.Ey;
            data.Gxy = app.Gxy;
            
            data.Sx = app.Sx;
            data.Sy = app.Sy;
            data.Sxy = app.Sxy;
            
            if isempty(app.X) || isempty(app.Y) || isempty(app.ncon)
                error('No mesh available. Generate the mesh first.');
            end
            
            if isempty(app.U) || isempty(app.Sx)
                error('No FEM results available. Press Solve first.');
            end

            selectedValue = app.IsolinesDropDown.Value;
            
            plotSelectedContour( ...
                app.UIAxes5_2, ...
                selectedValue, ...
                data);

        
        end

        % Button pushed function: LoadANSYSMeshButton
        function LoadANSYSMeshButtonPushed(app, event)
        %Read the spreadsheet
        %filename = 'ANSYSMESH';
        filename = 'newansysmesh_converted';

        app.ANSYSmesh = true;
        data = readtable(filename);

        %Total COUNTS from row 1 (the only row with n_element/n_node)
        app.n_elements = data.n_element(1);   % 880
        app.n_nodes    = data.n_node(1);      % 496

        % Extracts the first n_elements rows of ncon columns
        app.ncon = int32([data.ncon1(1:app.n_elements), ...
                          data.ncon2(1:app.n_elements), ...
                          data.ncon3(1:app.n_elements)]);

        % Gets the node coordiantes first n_nodes rows of X/Y columns
        % Row i corresponds to node i (1-indexed), so no mapping needed
        app.X = data.X(1:app.n_nodes);
        app.Y = data.Y(1:app.n_nodes);

        %Material properties
        app.E = data.E(1);
        app.v = data.v(1);
        app.t = data.t(1);
        
        %{
        %Forces
        if ismember('F', data.Properties.VariableNames)
            app.F = data.F(~isnan(data.F));
        end

        %Boundary conditions
        if ismember('dzero', data.Properties.VariableNames)
            app.dzero = data.dzero(~isnan(data.dzero));
        end
        if ismember('NDU', data.Properties.VariableNames)
            app.NDU = data.NDU(1);
        end
        %}
        %Checking for errors
        nconMax = double(max(app.ncon(:)));
        fprintf('Elements: %d | Nodes: %d | ncon range: [1, %d]\n', ...
            app.n_elements, app.n_nodes, nconMax);
        if nconMax > app.n_nodes
            warning('ncon max (%d) exceeds n_nodes (%d)!', nconMax, app.n_nodes);
        end

        %Plotting
        cla(app.UIAxes);
        hold(app.UIAxes, 'on');
        patch(app.UIAxes, ...
            'Faces',     app.ncon, ...
            'Vertices',  [app.X, app.Y], ...
            'FaceColor', 'none', ...
            'EdgeColor', [0 0 0]);
        axis(app.UIAxes, 'equal');
        title(app.UIAxes, 'ANSYS Mesh');
        xlabel(app.UIAxes, 'X');
        ylabel(app.UIAxes, 'Y');


        end

        % Button pushed function: ApplyButton_4
        function ApplyButton_4Pushed(app, event)
            %=====================BUTTON USED FOR THE APPLICATION OF
            %THERMAL CONSTRAINTS============================
            app.contactTemp = app.ToolContactTempEditField_2.Value;
            app.Tambient = app.ToolAmbientTempEditField.Value;
            app.thermalContact = app.ToolTipContactLengthmEditField.Value;
            

            valueB = app.BottomEdgeCheckBox_2.Value;
            if valueB == 1

                app.Tbotedge=true;

            else

                app.Tbotedge=false;

            end


            valueR = app.RightChamferCheckBox_2.Value;
            if valueR == 1

                app.Trightcham=true;

            else

                app.Trightcham=false;

            end


            valueT = app.TopRightEdgeCheckBox.Value;
            if valueT == 1

                app.Ttopredge=true;

            else

                app.Ttopredge=false;

            end

            if isempty(app.X) || isempty(app.Y) || isempty(app.ncon)
                error('Mesh has not been generated. Go to Mesh Generator and click Apply first.');
            end


            [app.fixedNodes, app.fixedValues, app.thermalTipNodes] = ApplyThermalBoundary(app.thermalContact, ...
    app.Tbotedge, app.Trightcham, app.Ttopredge, ...
    app.n_nodes, app.X, app.Y, ...
    app.Xb, app.Yb, ...
    app.X_body, app.Y_body, ...
    app.X_tri, app.Y_tri, ...
    app.bevelled, app.ANSYSmesh, app.normalMesh, ...
    app.Tambient, app.contactTemp);

if app.bevelled == true
    ambientNodes = setdiff(app.fixedNodes, app.thermalTipNodes);
    %{
            plot(app.UIAxes6, app.X_body, app.Y_body);
            hold(app.UIAxes6,'on')
            plot(app.UIAxes6, app.X_tri, app.Y_tri)

            % Plot clamped nodes
            scatter(app.UIAxes6, ...
                app.X(app.thermalTipNodes), ...
                app.Y(app.thermalTipNodes), ...
                70, 'filled', 'r')
            
            % Plot contact nodes
            %
            scatter(app.UIAxes6, ...
                app.X(ambientNodes), ...
                app.Y(ambientNodes), ...
                70, 'filled', 'b')
            axis(app.UIAxes6, 'equal');
            hold(app.UIAxes6,'off')
    %}

    %}


plot(app.UIAxes6, app.X_body, app.Y_body);
hold(app.UIAxes6,'on')
plot(app.UIAxes6, app.X_tri, app.Y_tri)

% Plot contact (thermal tip) nodes
h1 = scatter(app.UIAxes6, ...
    app.X(app.thermalTipNodes), ...
    app.Y(app.thermalTipNodes), ...
    70, 'filled', 'r');

% Plot ambient fixed nodes
h2 = scatter(app.UIAxes6, ...
    app.X(ambientNodes), ...
    app.Y(ambientNodes), ...
    70, 'filled', 'b');

axis(app.UIAxes6, 'equal');

legend(app.UIAxes6, ...
    [h1 h2], ...
    {'Contact Temperature Nodes', 'Ambient Boundary Nodes'});

hold(app.UIAxes6,'off')
end

if app.ANSYSmesh == true
ambientNodes = setdiff(app.fixedNodes, app.thermalTipNodes);
plot(app.UIAxes6, app.X, app.Y);
hold(app.UIAxes6,'on')

% Plot contact (thermal tip) nodes
h1 = scatter(app.UIAxes6, ...
    app.X(app.thermalTipNodes), ...
    app.Y(app.thermalTipNodes), ...
    70, 'filled', 'r');

% Plot ambient fixed nodes
h2 = scatter(app.UIAxes6, ...
    app.X(ambientNodes), ...
    app.Y(ambientNodes), ...
    70, 'filled', 'b');

axis(app.UIAxes6, 'equal');

legend(app.UIAxes6, ...
    [h1 h2], ...
    {'Contact Temperature Nodes', 'Ambient Boundary Nodes'});

hold(app.UIAxes6,'off')
end


if app.normalMesh == true
ambientNodes = setdiff(app.fixedNodes, app.thermalTipNodes);
plot(app.UIAxes6, app.Xb, app.Yb);
hold(app.UIAxes6,'on')

% Plot contact (thermal tip) nodes
h1 = scatter(app.UIAxes6, ...
    app.X(app.thermalTipNodes), ...
    app.Y(app.thermalTipNodes), ...
    70, 'filled', 'r');

% Plot ambient fixed nodes
h2 = scatter(app.UIAxes6, ...
    app.X(ambientNodes), ...
    app.Y(ambientNodes), ...
    70, 'filled', 'b');

axis(app.UIAxes6, 'equal');

legend(app.UIAxes6, ...
    [h1 h2], ...
    {'Contact Temperature Nodes', 'Ambient Boundary Nodes'});

hold(app.UIAxes6,'off')

end


        end

        % Button pushed function: ResetButton_4
        function ResetButton_4Pushed(app, event)
             %=====================BUTTON USED FOR THE APPLICATION OF
            %RESETTING THE THERMAL CONSTRAINTS============================
            
            app.fixedNodes = [];
            app.fixedValues = [];
            app.thermalTipNodes = [];
            cla(app.UIAxes6)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ToolGeometryTab = uitab(app.TabGroup);
            app.ToolGeometryTab.Title = 'Tool Geometry';
            app.UIAxes3 = uiaxes(app.ToolGeometryTab);
            title(app.UIAxes3, 'Tool Geometry')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.TopWidthmEditFieldLabel = uilabel(app.ToolGeometryTab);
            app.TopWidthmEditFieldLabel.Text = 'Top Width (m)';
            app.TopWidthmEditField = uieditfield(app.ToolGeometryTab, 'numeric');
            app.BottomWidthmEditFieldLabel = uilabel(app.ToolGeometryTab);
            app.BottomWidthmEditFieldLabel.Text = 'Bottom Width (m)';
            app.BottomWidthmEditField = uieditfield(app.ToolGeometryTab, 'numeric');
            app.HeightmEditFieldLabel = uilabel(app.ToolGeometryTab);
            app.HeightmEditFieldLabel.Text = 'Height (m)';
            app.HeightmEditField = uieditfield(app.ToolGeometryTab, 'numeric');
            app.ThicknessmEditFieldLabel = uilabel(app.ToolGeometryTab);
            app.ThicknessmEditFieldLabel.Text = 'Thickness (m)';
            app.ThicknessmEditField = uieditfield(app.ToolGeometryTab, 'numeric');
            app.LoadBevelledToolButton = uibutton(app.ToolGeometryTab, 'push');
            app.LoadBevelledToolButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBevelledToolButtonPushed, true);
            app.LoadBevelledToolButton.Text = 'Load Bevelled Tool';
            app.ToolGeometryLabel = uilabel(app.ToolGeometryTab);
            app.ToolGeometryLabel.Text = 'Tool Geometry';
            app.MaterialPropertiesLabel = uilabel(app.ToolGeometryTab);
            app.MaterialPropertiesLabel.Text = 'Material Properties';
            app.MaterialDropDownLabel = uilabel(app.ToolGeometryTab);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.ToolGeometryTab);
            app.MaterialDropDown.Items = {'Tungsten Carbide', 'M2 High Speed Steel'};
            app.MaterialDropDown.Value = 'Tungsten Carbide';
            app.ToolContactTempEditFieldLabel = uilabel(app.ToolGeometryTab);
            app.ToolContactTempEditFieldLabel.Text = 'Tool Contact Temp';
            app.ToolContactTempEditField = uieditfield(app.ToolGeometryTab, 'numeric');
            app.ResetButton = uibutton(app.ToolGeometryTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ApplyButton = uibutton(app.ToolGeometryTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.YoungsModulosGPaLabel = uilabel(app.ToolGeometryTab);
            app.YoungsModulosGPaLabel.Text = 'Youngs Modulos (GPa):';
            app.PoissonsRatioLabel = uilabel(app.ToolGeometryTab);
            app.PoissonsRatioLabel.Text = 'Poissons Ratio:';
            app.ToolContactTempLabel = uilabel(app.ToolGeometryTab);
            app.ToolContactTempLabel.Text = 'Tool Contact Temp: ';
            app.BevelledToolGeometryCheckBox = uicheckbox(app.ToolGeometryTab);
            app.BevelledToolGeometryCheckBox.ValueChangedFcn = createCallbackFcn(app, @BevelledToolGeometryCheckBoxValueChanged, true);
            app.BevelledToolGeometryCheckBox.Text = 'Bevelled Tool Geometry';
            app.StatusLampLabel = uilabel(app.ToolGeometryTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.ToolGeometryTab);
            app.MeshGeneratorTab = uitab(app.TabGroup);
            app.MeshGeneratorTab.Title = 'Mesh Generator';
            app.UIAxes = uiaxes(app.MeshGeneratorTab);
            title(app.UIAxes, 'Mesh')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MeshGeneratorLabel = uilabel(app.MeshGeneratorTab);
            app.MeshGeneratorLabel.Text = 'Mesh Generator';
            app.RefinedZonemEditFieldLabel = uilabel(app.MeshGeneratorTab);
            app.RefinedZonemEditFieldLabel.Text = 'Refined Zone (m)';
            app.RefinedZonemEditField = uieditfield(app.MeshGeneratorTab, 'numeric');
            app.DivisionsintherefinedzoneEditFieldLabel = uilabel(app.MeshGeneratorTab);
            app.DivisionsintherefinedzoneEditFieldLabel.Text = 'Divisions in the refined zone';
            app.DivisionsintherefinedzoneEditField = uieditfield(app.MeshGeneratorTab, 'numeric');
            app.DivisionsintherestEditFieldLabel = uilabel(app.MeshGeneratorTab);
            app.DivisionsintherestEditFieldLabel.Text = 'Divisions in the rest';
            app.DivisionsintherestEditField = uieditfield(app.MeshGeneratorTab, 'numeric');
            app.DivisionsintheYEditFieldLabel = uilabel(app.MeshGeneratorTab);
            app.DivisionsintheYEditFieldLabel.Text = 'Divisions in the Y';
            app.DivisionsintheYEditField = uieditfield(app.MeshGeneratorTab, 'numeric');
            app.ApplyButton_2 = uibutton(app.MeshGeneratorTab, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.ParametersalongtheXaxiasLabel = uilabel(app.MeshGeneratorTab);
            app.ParametersalongtheXaxiasLabel.Text = 'Parameters along the X axias';
            app.ParametersalongtheYaxisLabel = uilabel(app.MeshGeneratorTab);
            app.ParametersalongtheYaxisLabel.Text = 'Parameters along the Y axis';
            app.ResetButton_3 = uibutton(app.MeshGeneratorTab, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.LoadANSYSMeshButton = uibutton(app.MeshGeneratorTab, 'push');
            app.LoadANSYSMeshButton.ButtonPushedFcn = createCallbackFcn(app, @LoadANSYSMeshButtonPushed, true);
            app.LoadANSYSMeshButton.Text = 'Load ANSYS Mesh';
            app.LoadsandConstraintsTab = uitab(app.TabGroup);
            app.LoadsandConstraintsTab.Title = 'Loads and Constraints';
            app.UIAxes4 = uiaxes(app.LoadsandConstraintsTab);
            title(app.UIAxes4, 'Nodal Loads & Constraints')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.ApplyLoadsBoundaryConditionsLabel = uilabel(app.LoadsandConstraintsTab);
            app.ApplyLoadsBoundaryConditionsLabel.Text = 'Apply Loads & Boundary Conditions';
            app.BoundaryConstraintsLabel = uilabel(app.LoadsandConstraintsTab);
            app.BoundaryConstraintsLabel.Text = 'Boundary Constraints';
            app.AxialFeedForceNEditFieldLabel = uilabel(app.LoadsandConstraintsTab);
            app.AxialFeedForceNEditFieldLabel.Text = 'Axial Feed Force (N)';
            app.AxialFeedForceNEditField = uieditfield(app.LoadsandConstraintsTab, 'numeric');
            app.CuttingTangentialForceNEditFieldLabel = uilabel(app.LoadsandConstraintsTab);
            app.CuttingTangentialForceNEditFieldLabel.Text = 'Cutting Tangential Force (N)';
            app.CuttingTangentialForceNEditField = uieditfield(app.LoadsandConstraintsTab, 'numeric');
            app.BottomEdgeCheckBox = uicheckbox(app.LoadsandConstraintsTab);
            app.BottomEdgeCheckBox.ValueChangedFcn = createCallbackFcn(app, @BottomEdgeCheckBoxValueChanged, true);
            app.BottomEdgeCheckBox.Text = 'Bottom Edge';
            app.RightChamferCheckBox = uicheckbox(app.LoadsandConstraintsTab);
            app.RightChamferCheckBox.ValueChangedFcn = createCallbackFcn(app, @RightChamferCheckBoxValueChanged, true);
            app.RightChamferCheckBox.Text = 'Right Chamfer';
            app.TopHalfRightEdgeCheckBox = uicheckbox(app.LoadsandConstraintsTab);
            app.TopHalfRightEdgeCheckBox.ValueChangedFcn = createCallbackFcn(app, @TopHalfRightEdgeCheckBoxValueChanged, true);
            app.TopHalfRightEdgeCheckBox.Text = 'Top Half Right Edge';
            app.ApplyButton_3 = uibutton(app.LoadsandConstraintsTab, 'push');
            app.ApplyButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_3Pushed, true);
            app.ApplyButton_3.Text = 'Apply';
            app.ForceContactLengthmEditFieldLabel = uilabel(app.LoadsandConstraintsTab);
            app.ForceContactLengthmEditFieldLabel.Text = 'Force Contact Length (m)';
            app.ForceContactLengthmEditField = uieditfield(app.LoadsandConstraintsTab, 'numeric');
            app.ResetButton_2 = uibutton(app.LoadsandConstraintsTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.ThermalConstraintsTab = uitab(app.TabGroup);
            app.ThermalConstraintsTab.Title = 'Thermal Constraints';
            app.UIAxes6 = uiaxes(app.ThermalConstraintsTab);
            title(app.UIAxes6, 'Nodal Constraints')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.ToolTemperaturesLabel = uilabel(app.ThermalConstraintsTab);
            app.ToolTemperaturesLabel.Text = 'Tool Temperatures';
            app.ToolContactTempEditField_2Label = uilabel(app.ThermalConstraintsTab);
            app.ToolContactTempEditField_2Label.Text = 'Tool Contact Temp';
            app.ToolContactTempEditField_2 = uieditfield(app.ThermalConstraintsTab, 'numeric');
            app.ToolAmbientTempEditFieldLabel = uilabel(app.ThermalConstraintsTab);
            app.ToolAmbientTempEditFieldLabel.Text = 'Tool Ambient Temp';
            app.ToolAmbientTempEditField = uieditfield(app.ThermalConstraintsTab, 'numeric');
            app.BottomEdgeCheckBox_2 = uicheckbox(app.ThermalConstraintsTab);
            app.BottomEdgeCheckBox_2.Text = 'Bottom Edge';
            app.RightChamferCheckBox_2 = uicheckbox(app.ThermalConstraintsTab);
            app.RightChamferCheckBox_2.Text = 'Right Chamfer';
            app.TopRightEdgeCheckBox = uicheckbox(app.ThermalConstraintsTab);
            app.TopRightEdgeCheckBox.Text = 'Top Right Edge';
            app.BoundaryConstraintsLabel_2 = uilabel(app.ThermalConstraintsTab);
            app.BoundaryConstraintsLabel_2.Text = 'Boundary Constraints';
            app.ThermalConstraintParametersLabel = uilabel(app.ThermalConstraintsTab);
            app.ThermalConstraintParametersLabel.Text = 'Thermal Constraint Parameters';
            app.ApplyButton_4 = uibutton(app.ThermalConstraintsTab, 'push');
            app.ApplyButton_4.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_4Pushed, true);
            app.ApplyButton_4.Text = 'Apply';
            app.ResetButton_4 = uibutton(app.ThermalConstraintsTab, 'push');
            app.ResetButton_4.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_4Pushed, true);
            app.ResetButton_4.Text = 'Reset';
            app.ToolTipContactLengthmEditFieldLabel = uilabel(app.ThermalConstraintsTab);
            app.ToolTipContactLengthmEditFieldLabel.Text = 'Tool Tip Contact Length (m)';
            app.ToolTipContactLengthmEditField = uieditfield(app.ThermalConstraintsTab, 'numeric');
            app.SolveTab = uitab(app.TabGroup);
            app.SolveTab.Title = 'Solve';
            app.UIAxes5 = uiaxes(app.SolveTab);
            title(app.UIAxes5, 'Elostatic Analysis')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5_2 = uiaxes(app.SolveTab);
            title(app.UIAxes5_2, 'Thermal Analysis')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            zlabel(app.UIAxes5_2, 'Z')
            app.IsolinesDropDownLabel = uilabel(app.SolveTab);
            app.IsolinesDropDownLabel.Text = 'Isolines';
            app.IsolinesDropDown = uidropdown(app.SolveTab);
            app.IsolinesDropDown.Items = {'Sx', 'Sy', 'Sxy', 'Ux', 'Uy', 'Uxy', 'Ex', 'Ey', 'Exy'};
            app.IsolinesDropDown.Value = 'Sx';
            app.PatchDropDownLabel = uilabel(app.SolveTab);
            app.PatchDropDownLabel.Text = 'Patch';
            app.PatchDropDown = uidropdown(app.SolveTab);
            app.PatchDropDown.Items = {'Sx', 'Sy', 'Sxy', 'Ux', 'Uy', 'Uxy', 'Ex', 'Ey', 'Exy', 'Temperature'};
            app.PatchDropDown.Value = 'Sx';
            app.SolveButton = uibutton(app.SolveTab, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.ContoursLabel = uilabel(app.SolveTab);
            app.ContoursLabel.Text = 'Contours';
            app.ScaleEditFieldLabel = uilabel(app.SolveTab);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.SolveTab, 'numeric');
            app.PlotButton = uibutton(app.SolveTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlotButton_2 = uibutton(app.SolveTab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thermal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end