classdef thermal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ToolGeometryTab                 matlab.ui.container.Tab
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        BevelledToolGeometryCheckBox    matlab.ui.control.CheckBox
        ToolContactTempLabel            matlab.ui.control.Label
        PoissonsRatioLabel              matlab.ui.control.Label
        YoungsModulosGPaLabel           matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        MaterialDropDown                matlab.ui.control.DropDown
        MaterialDropDownLabel           matlab.ui.control.Label
        MaterialPropertiesLabel         matlab.ui.control.Label
        ToolGeometryLabel               matlab.ui.control.Label
        LoadBevelledToolButton          matlab.ui.control.Button
        ThicknessmEditField             matlab.ui.control.NumericEditField
        ThicknessmEditFieldLabel        matlab.ui.control.Label
        HeightmEditField                matlab.ui.control.NumericEditField
        HeightmEditFieldLabel           matlab.ui.control.Label
        BottomWidthmEditField           matlab.ui.control.NumericEditField
        BottomWidthmEditFieldLabel      matlab.ui.control.Label
        TopWidthmEditField              matlab.ui.control.NumericEditField
        TopWidthmEditFieldLabel         matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        MeshGeneratorTab                matlab.ui.container.Tab
        LoadANSYSMeshButton             matlab.ui.control.Button
        ResetButton_3                   matlab.ui.control.Button
        ParametersalongtheYaxisLabel    matlab.ui.control.Label
        ParametersalongtheXaxiasLabel   matlab.ui.control.Label
        ApplyButton_2                   matlab.ui.control.Button
        DivisionsintheYEditField        matlab.ui.control.NumericEditField
        DivisionsintheYEditFieldLabel   matlab.ui.control.Label
        DivisionsintherestEditField     matlab.ui.control.NumericEditField
        DivisionsintherestEditFieldLabel  matlab.ui.control.Label
        DivisionsintherefinedzoneEditField  matlab.ui.control.NumericEditField
        DivisionsintherefinedzoneEditFieldLabel  matlab.ui.control.Label
        RefinedZonemEditField           matlab.ui.control.NumericEditField
        RefinedZonemEditFieldLabel      matlab.ui.control.Label
        MeshGeneratorLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        LoadsandConstraintsTab          matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        ResetButton_2                   matlab.ui.control.Button
        ForceContactLengthmEditField    matlab.ui.control.NumericEditField
        ForceContactLengthmEditFieldLabel  matlab.ui.control.Label
        ApplyButton_3                   matlab.ui.control.Button
        TopHalfRightEdgeCheckBox        matlab.ui.control.CheckBox
        RightChamferCheckBox            matlab.ui.control.CheckBox
        BottomEdgeCheckBox              matlab.ui.control.CheckBox
        CuttingTangentialForceNEditField  matlab.ui.control.NumericEditField
        CuttingTangentialForceNEditFieldLabel  matlab.ui.control.Label
        AxialFeedForceNEditField        matlab.ui.control.NumericEditField
        AxialFeedForceNEditFieldLabel   matlab.ui.control.Label
        BoundaryConstraintsLabel        matlab.ui.control.Label
        ApplyLoadsBoundaryConditionsLabel  matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        ThermalConstraintsTab           matlab.ui.container.Tab
        ConvectionCoefficienthEditField  matlab.ui.control.NumericEditField
        ConvectionCoefficienthEditFieldLabel  matlab.ui.control.Label
        FreeStreamTemperatureCEditField_2  matlab.ui.control.NumericEditField
        FreeStreamTemperatureCEditField_2Label  matlab.ui.control.Label
        ToolTipContactLengthmEditField  matlab.ui.control.NumericEditField
        ToolTipContactLengthmEditFieldLabel  matlab.ui.control.Label
        ResetButton_4                   matlab.ui.control.Button
        ApplyButton_4                   matlab.ui.control.Button
        ThermalConstraintParametersLabel  matlab.ui.control.Label
        FixedTemperatureConstraintsLabel  matlab.ui.control.Label
        TopRightEdgeCheckBox            matlab.ui.control.CheckBox
        RightChamferCheckBox_2          matlab.ui.control.CheckBox
        BottomEdgeCheckBox_2            matlab.ui.control.CheckBox
        FixedTemperatureCEditField_2    matlab.ui.control.NumericEditField
        FixedTemperatureCEditField_2Label  matlab.ui.control.Label
        ThermalParametersLabel_2        matlab.ui.control.Label
        UIAxes6                         matlab.ui.control.UIAxes
        SolveTab                        matlab.ui.container.Tab
        SolveMenuLabel                  matlab.ui.control.Label
        SolveforfullanalysisLabel       matlab.ui.control.Label
        ThermaldistributioncanbeplottedunderneaththepatchdropdownLabel  matlab.ui.control.Label
        PlotButton_2                    matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        ScaleEditField                  matlab.ui.control.NumericEditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        ContoursLabel                   matlab.ui.control.Label
        SolveButton                     matlab.ui.control.Button
        PatchDropDown                   matlab.ui.control.DropDown
        PatchDropDownLabel              matlab.ui.control.Label
        IsolinesDropDown                matlab.ui.control.DropDown
        IsolinesDropDownLabel           matlab.ui.control.Label
        UIAxes5_2                       matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        GeneralTab                      matlab.ui.container.Tab
        RESETButton                     matlab.ui.control.Button
        PlotButton_3                    matlab.ui.control.Button
        GeneralThermalFieldsSolverLabel  matlab.ui.control.Label
        SolveButton_2                   matlab.ui.control.Button
        GenerateMeshButton              matlab.ui.control.Button
        PlotandApplyButton              matlab.ui.control.Button
        DivisionsintheYEditField_2      matlab.ui.control.NumericEditField
        DivisionsintheYEditField_2Label  matlab.ui.control.Label
        DivisionsintheXEditField        matlab.ui.control.NumericEditField
        DivisionsintheXEditFieldLabel   matlab.ui.control.Label
        MeshGeneratorLabel_2            matlab.ui.control.Label
        ConductivityConstantKxKyEditField  matlab.ui.control.NumericEditField
        ConductivityConstantKxKyEditFieldLabel  matlab.ui.control.Label
        ConvectionConstanthEditField    matlab.ui.control.NumericEditField
        ConvectionConstanthEditFieldLabel  matlab.ui.control.Label
        UniformHeatSourceQEditField     matlab.ui.control.NumericEditField
        UniformHeatSourceQEditFieldLabel  matlab.ui.control.Label
        FreeStreamTemperatureCEditField  matlab.ui.control.NumericEditField
        FreeStreamTemperatureCEditFieldLabel  matlab.ui.control.Label
        FixedTemperatureCEditField      matlab.ui.control.NumericEditField
        FixedTemperatureCEditFieldLabel  matlab.ui.control.Label
        ThermalParametersLabel          matlab.ui.control.Label
        ThicknessmEditField_2           matlab.ui.control.NumericEditField
        ThicknessmEditField_2Label      matlab.ui.control.Label
        BottomSideDropDown              matlab.ui.control.DropDown
        BottomSideDropDownLabel         matlab.ui.control.Label
        TopSideDropDown                 matlab.ui.control.DropDown
        TopSideDropDownLabel            matlab.ui.control.Label
        RightSideDropDown               matlab.ui.control.DropDown
        RightSideDropDownLabel          matlab.ui.control.Label
        LeftSideDropDown                matlab.ui.control.DropDown
        LeftSideDropDownLabel           matlab.ui.control.Label
        BoundaryConstraintsLabel_3      matlab.ui.control.Label
        LengthmEditField                matlab.ui.control.NumericEditField
        LengthmEditFieldLabel           matlab.ui.control.Label
        GeometryLabel                   matlab.ui.control.Label
        WidthmEditField                 matlab.ui.control.NumericEditField
        WidthmEditFieldLabel            matlab.ui.control.Label
        UIAxes7                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description

        n_elements % Number of elements generated in the mesh
        n_nodes %Number of nodes in the generated mesh

        E %Youngs modulus of the selected material
        A %Area of the element
        
        ncon %Connection between nodes

        X %Coordiantes of nodes 
        Y

        NDU %Nodal degree of freedom   
        dzero %Setting some nodes to zero degrees of freedom (no displacment)

        F %Force vector acting on the nodes 
        v %Poissons ratio
        t %Thickeness of material

        T              % Nodal temperature result
        k_tool         % Thermal conductivity of main tool material
        k_BUE          % Thermal conductivity of BUE / stagnation zone
        Tambient       % Ambient temperature
        thermalNodes   % Thermal fixed temperature nodes
        thermalValues  % Prescribed temperature values

        % Initiate Matrices
        KE %Elemntal stiffness matrix
        K  %Global sitffness matrix of the piece
    
        KM %Duplicating 

        
        Wt %Top width of the isoceles trapezium
        Wb %Bottom width of the isoceles trapezium
        H %Height of isoceles trapezium

        U %Displacemnt matrix

        Ex %Strain in the X
        Ey %Strain in the Y
        Gxy %Shearing strain 

        Sx %Stress in the X
        Sy % Stress in the Y
        Sxy % Shearing stress

        Ux %Displacment in the X
        Uy %Displacement in the Y
        Umag %magnitude of the total displacement

        yield_strength %yeild strength of the material (optional)
        tensile_strength %Ultimate tensile strength
        compressive_strength %Compressive strength
        Ductile %How ductile is material
        Xb %X coordinates of the tool geometry boundary
        Yb %Y coordiantes of the tool geometry boundary
        Sim_strength
        ToolApp
        MeshAppp
        

        L_ref% refined length (from left)
        nx_ref% divisions in refined zone
        nx_coarse% divisions in rest
        ny%Divisions in the Y direction

        resetmesh %Keep track of the reset mesh state
        ANSYSmesh %keep track of the laoding ansys mesh

        Fc %Cutting force
        Ft %Thrust force
        Tc %Force Contact Length
        scale %Scale of deformity

        contactTemp

        check

        X_body
        Y_body
        X_tri
        Y_tri

        material

        botedge
        rightcham
        topredge

        bevelled

        clamp


        Xdef 
        Ydef
        Xorig
        Yorig

        normalMesh
        

        clampedNodes
        contactNodes
        

        thermalContact

        Tbotedge
        Trightcham
        Ttopredge

        fixedNodes
        fixedValues

        thermalTipNodes


        %Variables for the general thermal fields solver
        length
        width
        thickness

        fixedTemp
        freeTemp
        uniformHeat
        convConst
        conductConst

        divX
        divY

        X_g
        Y_g

        insulatedNodes
        convectionNodes
        Xg
        Yg
        ncong %Nodal connections 
        n_nodesg %Number of ndoes in the general solver mesh
        n_elementsg %Number of elements of the general solver mesh

        T_general %This is the temperature vector for the general solver

        h %Convection coefficient
        convEdges %Stored the edges that experience convection

        freeStreamT %Free stream temperature for the application of convection

        a2 %Chip Thickness (mm)
    end
    
    methods (Access = private)
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %{
            app.TopWidthmEditField.Enable = 'off';
            app.BottomWidthmEditField.Enable = 'off';
            app.HeightmEditField.Enable = 'off';
            app.ThicknessmEditField.Enable = 'off';
            %}
            app.LoadBevelledToolButton.Enable = 'off';
            app.normalMesh = false;
            app.bevelled = false;
            app.ANSYSmesh = false;
            app.check = false;
            
            app.X = [];
            app.Y = [];
            app.ncon = [];
            app.material = [];
            app.F = [];
            app.dzero = [];
            app.NDU = [];
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.StatusLamp.Color = 'red';
            app.Xb = [];
            app.Yb = [];


            app.E = 0;
            app.v = 0;
            app.contactTemp = 0;
            app.t = 0;
                
            cla(app.UIAxes3)
            plot(app.UIAxes3, app.Xb, app.Yb);
            axis(app.UIAxes3, 'equal');
            
            app.check = false;
            app.bevelled = false;
            app.normalMesh = false;
            app.ANSYSmesh = false;
            app.YoungsModulosGPaLabel.Text = sprintf('Youngs Modulus (GPa): %.2f', app.E);
            app.PoissonsRatioLabel.Text = sprintf('Poissons Ratio: %.2f', app.v);
            app.ToolContactTempLabel.Text = sprintf('Tool Contact Temperature (C): %.2f', app.contactTemp);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % =========================================================
            % GEOMETRY SELECTION
            % =========================================================
            % Use the checkbox/app.bevelled flag directly.
            % Do not rely only on app.check, because if app.check is false
            % the app accidentally creates a zero-size custom tool.
            
            if app.BevelledToolGeometryCheckBox.Value == true || app.bevelled == true
            
                app.bevelled = true;
                app.normalMesh = false;
                app.ANSYSmesh = false;
                app.check = true;
            
                % If bevelled geometry has not been loaded yet, create it here.
                if isempty(app.X_body) || isempty(app.Y_body) || isempty(app.X_tri) || isempty(app.Y_tri)
            
                    app.X_body = [
                        0.0002533
                        0.012
                        0.0112
                        0.00078
                        0.0000123
                        0.0002533
                    ];
            
                    app.Y_body = [
                         0
                         0
                        -0.004
                        -0.004
                        -0.0002121
                         0
                    ];
            
                    app.X_tri = [
                        0
                        0.0002533
                        0.0000123
                        0
                    ];
            
                    app.Y_tri = [
                         0
                         0
                        -0.0002121
                         0
                    ];
                end
            
                cla(app.UIAxes3)
                plot(app.UIAxes3, app.X_body, app.Y_body, 'b-');
                hold(app.UIAxes3,'on')
                plot(app.UIAxes3, app.X_tri, app.Y_tri, 'r-');
                axis(app.UIAxes3, 'equal');
                grid(app.UIAxes3, 'on');
                hold(app.UIAxes3,'off')
            
                app.t = 0.001;
                app.ThicknessmEditField.Value = app.t;
                app.StatusLamp.Color = 'green';
            
            else
            
                app.Wt = app.TopWidthmEditField.Value;
                app.Wb = app.BottomWidthmEditField.Value;
                app.H  = app.HeightmEditField.Value;
            
                [app.Xb, app.Yb] = CreateCustomTool(app.Wt, app.Wb, app.H);
            
                app.normalMesh = true;
                app.bevelled = false;
                app.ANSYSmesh = false;
                app.check = false;
            
                cla(app.UIAxes3)
                plot(app.UIAxes3, app.Xb, app.Yb);
                axis(app.UIAxes3, 'equal');
                grid(app.UIAxes3, 'on');
            
                app.t = app.ThicknessmEditField.Value;

                app.a2 = 0.7; %Chip Thickness for the Conventional Tool (mm)
            
            end
            
            if app.bevelled == true
                app.t = 0.001;   % bevelled tool default thickness
            else
                app.t = app.ThicknessmEditField.Value;
            end

            %app.contactTemp = app.ToolContactTempEditField.Value;

            material = app.MaterialDropDown.Value;
            switch material

                case 'Tungsten Carbide'
                    app.E = 640e9;
                    %app.E = 510e9;
                    app.v = 0.2;
                    %app.v = 0.3;
            
                    % Initial assumed thermal conductivity for tungsten carbide
                    app.k_tool = 60;      % W/(m.K)
            
                case 'M2 High Speed Steel'
                    app.E = 200e9;
                    app.v = 0.29;
            
                    % Initial assumed thermal conductivity for M2 HSS
                    app.k_tool = 25;      % W/(m.K)
            end
            
            % BUE/stagnation zone assumed steel-like for now
            app.k_BUE = 45;              % W/(m.K)
            
            % Ambient temperature for outer boundaries
            %app.Tambient = 150;           % deg C

            disp('***Youngs Modulos***')
            disp(app.E);
            disp('***Poissons Ratio***')
            disp(app.v);

            disp('Boundary coordinates for the tool geometry X, Y')
            disp(app.Xb);
            disp(app.Yb);

            app.YoungsModulosGPaLabel.Text = sprintf('Youngs Modulus (GPa): %.2f', (app.E/10^9));
            app.PoissonsRatioLabel.Text = sprintf('Poissons Ratio: %.2f', app.v);
            app.ToolContactTempLabel.Text = sprintf('Tool Contact Temperature (C): %.2f', app.contactTemp);
        end

        % Value changed function: BevelledToolGeometryCheckBox
        function BevelledToolGeometryCheckBoxValueChanged(app, event)
            value = app.BevelledToolGeometryCheckBox.Value;
            if value == 1
                %User can now laod Bevelled tool geometry
                app.LoadBevelledToolButton.Enable = 'on';

                %Grey out option to input values for custom geometry
                app.TopWidthmEditField.Enable = 'off';
                app.BottomWidthmEditField.Enable = 'off';
                app.HeightmEditField.Enable = 'off';
                app.ThicknessmEditField.Enable = 'off';
            
            else
                app.LoadBevelledToolButton.Enable = 'off';
                %Return option tool input custom tool parameters
                app.TopWidthmEditField.Enable = 'on';
                app.BottomWidthmEditField.Enable = 'on';
                app.HeightmEditField.Enable = 'on';
                app.ThicknessmEditField.Enable = 'on';

                app.StatusLamp.Color = 'red';
            end
            
        end

        % Button pushed function: LoadBevelledToolButton
        function LoadBevelledToolButtonPushed(app, event)
            app.StatusLamp.Color = 'green';
            app.t = 0.001;
            app.ThicknessmEditField.Value = app.t;
            app.check = true;
            app.bevelled = true;
            app.normalMesh = false;
            app.ANSYSmesh = false;

%{
%% =========================
%  MAIN TOOL BODY
%  Counter-clockwise ordering
%  Starting from top-left
%  =========================
app.X_body = [
    0.0002533
    0.012
    0.0112
    0.00078
    0.0000123
    0.0002533
];
app.Y_body = [
     0
     0
    -0.004
    -0.004
    -0.0002121
     0
];
%% =========================
%  SMALL RED TRIANGLE
%  Counter-clockwise ordering
%  =========================
app.X_tri = [
    0
    0.0002533
    0.0000123
    0
];
app.Y_tri = [
     0
     0
    -0.0002121
     0
];
%}

%trying a new ordering for the bevelled tool

app.X_body = [0.0002533 0.0000123 0.00078 0.006 0.0112 0.012 0.006 0.0005533 0.0002533];
app.Y_body = [0 -0.0002121 -0.004 -0.004 -0.004 0 0 0 0];

app.X_tri = [0 0.0000123 0.0002533 0];
app.Y_tri = [0 -0.0002121 0 0];
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
        cla(app.UIAxes)

            if app.bevelled == true

                nx = 10;
                
                %
                [app.X, app.Y, app.ncon, app.material, app.n_nodes, app.n_elements] = GenerateBevelToolMesh( ...
                    app.X_body, app.Y_body, ...
                    app.X_tri, app.Y_tri, ...
                    nx);
                %}
                
                %{
                %===========TESTING NEW MESH ZONE=======================
                app.L_ref = app.RefinedZonemEditField.Value;
                app.nx_ref = app.DivisionsintherefinedzoneEditField.Value;
                app.nx_coarse = app.DivisionsintherestEditField.Value;
                app.ny = app.DivisionsintheYEditField.Value;

                [app.X, app.Y, app.ncon, app.material, app.n_nodes, app.n_elements] = GenerateBevelToolMesh2(...
                    app.X_body, app.Y_body, app.X_tri, app.Y_tri, app.L_ref, app.nx_ref, app.nx_coarse, app.ny);

                %====================================================
                %}
                patch(app.UIAxes, ...
                    'Faces', app.ncon, ...
                    'Vertices', [app.X app.Y], ...
                    'FaceColor', 'none', ...
                    'EdgeColor', 'k');

                axis(app.UIAxes, 'equal');
                grid(app.UIAxes, 'on');

            elseif app.normalMesh == true

                app.L_ref = app.RefinedZonemEditField.Value;
                app.nx_ref = app.DivisionsintherefinedzoneEditField.Value;
                app.nx_coarse = app.DivisionsintherestEditField.Value;
                app.ny = app.DivisionsintheYEditField.Value;

                [app.X, app.Y, app.ncon, app.n_nodes, app.n_elements] = GenerateCustomToolMesh( ...
                    app.Xb, app.Yb, ...
                    app.L_ref, app.nx_ref, app.nx_coarse, app.ny);

                app.material = ones(app.n_elements,1);

                patch(app.UIAxes, ...
                    'Faces', app.ncon, ...
                    'Vertices', [app.X app.Y], ...
                    'FaceColor', 'none', ...
                    'EdgeColor', 'k');

                axis(app.UIAxes, 'equal');
                grid(app.UIAxes, 'on');

            else

                error('No valid geometry/mesh type selected.');

            end   
        end

        % Button pushed function: ApplyButton_3
        function ApplyButton_3Pushed(app, event)
            app.Ft = app.AxialFeedForceNEditField.Value;
            app.Fc = app.CuttingTangentialForceNEditField.Value;
            app.Tc = app.ForceContactLengthmEditField.Value;
            
            valueB = app.BottomEdgeCheckBox.Value;
            if valueB == 1

                app.botedge=true;

            else

                app.botedge=false;

            end


            valueR = app.RightChamferCheckBox.Value;
            if valueR == 1

                app.rightcham=true;

            else

                app.rightcham=false;

            end


            valueT = app.TopHalfRightEdgeCheckBox.Value;
            if valueT == 1

                app.topredge=true;

            else

                app.topredge=false;

            end

            if isempty(app.X) || isempty(app.Y) || isempty(app.ncon)
                error('Mesh has not been generated. Go to Mesh Generator and click Apply first.');
            end
            [app.F, app.NDU, app.dzero,app.clampedNodes, app.contactNodes] = ApplyLoadsBoundary(app.Ft, app.Fc, app.Tc, app.botedge, app.rightcham,app.topredge,app.n_nodes,app.X,app.Y,app.Xb,...
                app.Yb,app.X_body,app.Y_body,app.X_tri,app.Y_tri,app.bevelled, app.ANSYSmesh, app.normalMesh);

cla(app.UIAxes4)

% Plot all nodes
if app.bevelled == true
            plot(app.UIAxes4, app.X_body, app.Y_body);
            hold(app.UIAxes4,'on')
            plot(app.UIAxes4, app.X_tri, app.Y_tri)

            % Plot clamped nodes
            scatter(app.UIAxes4, ...
                app.X(app.clampedNodes), ...
                app.Y(app.clampedNodes), ...
                70, 'filled', 'r')
            
            % Plot contact nodes
            scatter(app.UIAxes4, ...
                app.X(app.contactNodes), ...
                app.Y(app.contactNodes), ...
                70, 'filled', 'b')
            axis(app.UIAxes4, 'equal');
            hold(app.UIAxes4,'off')
end
if app.normalMesh == true
plot(app.UIAxes4, app.Xb, app.Yb)
hold(app.UIAxes4, 'on')

% Plot clamped nodes
scatter(app.UIAxes4, ...
    app.X(app.clampedNodes), ...
    app.Y(app.clampedNodes), ...
    70, 'filled', 'r')

% Plot contact nodes
scatter(app.UIAxes4, ...
    app.X(app.contactNodes), ...
    app.Y(app.contactNodes), ...
    70, 'filled', 'b')

hold(app.UIAxes4, 'off')

axis(app.UIAxes4, 'equal')
grid(app.UIAxes4, 'on')
end

if app.ANSYSmesh == true
plot(app.UIAxes4, app.X, app.Y)
hold(app.UIAxes4, 'on')

% Plot clamped nodes
scatter(app.UIAxes4, ...
    app.X(app.clampedNodes), ...
    app.Y(app.clampedNodes), ...
    70, 'filled', 'r')

% Plot contact nodes
scatter(app.UIAxes4, ...
    app.X(app.contactNodes), ...
    app.Y(app.contactNodes), ...
    70, 'filled', 'b')

hold(app.UIAxes4, 'off')

axis(app.UIAxes4, 'equal')
grid(app.UIAxes4, 'on')

end


        end

        % Value changed function: BottomEdgeCheckBox
        function BottomEdgeCheckBoxValueChanged(app, event)
            valueB = app.BottomEdgeCheckBox.Value;

            if valueB == 1

                app.botedge=true;

            else

                app.botedge=false;

            end
            
        end

        % Value changed function: RightChamferCheckBox
        function RightChamferCheckBoxValueChanged(app, event)
            valueR = app.RightChamferCheckBox.Value;
            if valueR == 1

                app.rightcham=true;

            else

                app.rightcham=false;

            end
            
        end

        % Value changed function: TopHalfRightEdgeCheckBox
        function TopHalfRightEdgeCheckBoxValueChanged(app, event)
            valueT = app.TopHalfRightEdgeCheckBox.Value;

            if valueT == 1

                app.topredge=true;

            else

                app.topredge=false;

            end
            
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.F = [];
            app.NDU = [];
            app.dzero = [];
            app.clampedNodes = [];
            app.contactNodes = [];
            cla(app.UIAxes4)


        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.X = [];
            app.Y = [];
            app.ncon = [];
            app.n_nodes = [];
            app.n_elements = [];
            cla(app.UIAxes);
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)

            if isempty(app.X) || isempty(app.Y) || isempty(app.ncon)
                error('No mesh available. Generate the mesh first.');
            end
            
            if isempty(app.F) || isempty(app.dzero) || isempty(app.NDU)
                error('Loads and boundary conditions have not been applied. Go to Loads and Constraints and click Apply first.');
            end
            
            if isempty(app.material)
                app.material = ones(app.n_elements,1);
            end
            
            [app.U, app.Ux, app.Uy, app.Umag, ...
             app.Ex, app.Ey, app.Gxy, ...
             app.Sx, app.Sy, app.Sxy] = ...
             SolveFEM( ...
                app.n_nodes, ...
                app.n_elements, ...
                app.ncon, ...
                app.X, ...
                app.Y, ...
                app.E, ...
                app.v, ...
                app.t, ...
                app.F, ...
                app.dzero, ...
                app.NDU, ...
                app.material);
                            
            
               [app.Xdef, app.Ydef, app.Xorig, app.Yorig] = ...
                display_structure( ...
                    app.n_elements,...
                    app.ncon,...
                    app.X,...
                    app.Y,...
                    app.U,...
                    app.ScaleEditField.Value);
            
            
               cla(app.UIAxes5)
               cla(app.UIAxes5_2)
            hold(app.UIAxes5,'on')
            
            for i = 1:app.n_elements
            
                %{
                % Original mesh
                plot(app.UIAxes, ...
                    Xorig(i,:), ...
                    Yorig(i,:), ...
                    'k-')
                %}
                % Deformed mesh
                plot(app.UIAxes5, ...
                    app.Xdef(i,:), ...
                    app.Ydef(i,:), ...
                    'r-','LineWidth',1.5)
            
            end
            
            axis(app.UIAxes5,'equal')
            grid(app.UIAxes5,'on')
            
            hold(app.UIAxes5,'off')

            % =========================================================
            % THERMAL ANALYSIS
            % =========================================================
            
            app.thermalNodes = app.fixedNodes;
            app.thermalValues = app.fixedValues;
            %{
            app.T = SolveThermal( ...
                app.n_nodes, ...
                app.n_elements, ...
                app.ncon, ...
                app.X, ...
                app.Y, ...
                app.t, ...
                app.material, ...
                app.k_tool, ...
                app.k_BUE, ...
                app.thermalNodes, ...
                app.thermalValues);
            %}
disp(app.freeStreamT)

            app.T = SolveThermal( ...
    app.n_nodes, app.n_elements, ...
    app.ncon, app.X, app.Y, ...
    app.t, ...
    app.material, ...
    app.k_tool, app.k_BUE, ...
    app.fixedNodes, app.fixedValues, ...
    app.convEdges, app.h, app.freeStreamT);
            
            plotThermalPatch( ...
                app.UIAxes5_2, ...
                app.X, ...
                app.Y, ...
                app.ncon, ...
                app.T);
            
            fprintf('Thermal analysis complete.\n');
            fprintf('Minimum temperature = %.3f C\n', min(app.T));
            fprintf('Maximum temperature = %.3f C\n', max(app.T));            

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
           
            % =========================================================
            % RESET AXES COMPLETELY
            % =========================================================
            ax = app.UIAxes5_2;
            
            cla(ax,'reset')
            hold(ax,'off')
            
            % delete old colorbars
            delete(findall(app.UIFigure,'Type','ColorBar'))
            
            % =========================================================
            % GET DROPDOWN VALUE
            % =========================================================
            resultName = app.PatchDropDown.Value;
            
            % =========================================================
            % SELECT DATA + PLOTTING MODE
            % =========================================================
            switch resultName

            % -----------------------------------------------------
            % ELEMENT-WISE STRESSES AND STRAINS
            % -----------------------------------------------------
            case 'Sx'
        
                fieldData = averageElementToNodes(app.ncon, app.n_nodes, app.Sx);
                faceMode = 'interp';
                plotTitle = 'Stress Contour \sigma_x';
            
            case 'Sy'
            
                fieldData = averageElementToNodes(app.ncon, app.n_nodes, app.Sy);
                faceMode = 'interp';
                plotTitle = 'Stress Contour \sigma_y';
            
            case 'Sxy'
            
                fieldData = averageElementToNodes(app.ncon, app.n_nodes, app.Sxy);
                faceMode = 'interp';
                plotTitle = 'Stress Contour \sigma_{xy}';
            
            case 'Ex'
            
                fieldData = averageElementToNodes(app.ncon, app.n_nodes, app.Ex);
                faceMode = 'interp';
                plotTitle = 'Strain Contour \epsilon_x';
            
            case 'Ey'
            
                fieldData = averageElementToNodes(app.ncon, app.n_nodes, app.Ey);
                faceMode = 'interp';
                plotTitle = 'Strain Contour \epsilon_y';
            
            case 'Exy'
            
                fieldData = averageElementToNodes(app.ncon, app.n_nodes, app.Gxy);
                faceMode = 'interp';
                plotTitle = 'Strain Contour \epsilon_{xy}';
        
            % -----------------------------------------------------
            % NODAL DISPLACEMENTS
            % -----------------------------------------------------
            case 'Ux'
        
                fieldData = app.Ux;
                faceMode = 'flat';
                plotTitle = 'Displacement U_x';
        
            case 'Uy'
        
                fieldData = app.Uy;
                faceMode = 'interp';
                plotTitle = 'Displacement U_y';
        
            case 'Uxy'
        
                fieldData = app.Umag;
                faceMode = 'interp';
                plotTitle = 'Displacement |U|';

            case 'Temperature'
            
                if isempty(app.T)
                    error('Temperature field not available. Press Solve first.');
                end
            
                fieldData = app.T;
                faceMode = 'interp';
                plotTitle = 'Temperature Distribution';            
        
            otherwise
        
                error('Unknown dropdown selection')
        
        end

% =========================================================
% DRAW PATCH CONTOUR
% =========================================================
patch(ax, ...
    'Faces', app.ncon, ...
    'Vertices', [app.X app.Y], ...
    'FaceVertexCData', fieldData, ...
    'FaceColor', faceMode, ...
    'EdgeColor', 'none');

axis(ax,'equal')
grid(ax,'on')

xlim(ax, [min(app.X) max(app.X)]);
ylim(ax, [min(app.Y) max(app.Y)]);

colormap(ax,'jet')

cb = colorbar(ax);

if isempty(app.X) || isempty(app.Y) || isempty(app.ncon)
    error('No mesh available. Generate the mesh first.');
end

if isempty(app.U) || isempty(app.Sx)
    error('No FEM results available. Press Solve first.');
end

if strcmp(resultName,'Temperature')
    cb.Label.String = 'Temperature (°C)';
    colormap(ax,'jet')
else
    cb.Label.String = resultName;
    colormap(ax,'jet')
end

title(ax, plotTitle)

xlabel(ax,'X')
ylabel(ax,'Y')


        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            cla(app.UIAxes5_2)
            data.X = app.X;
            data.Y = app.Y;
            
            data.ncon = app.ncon;
            data.n_nodes = app.n_nodes;
            
            data.Ux = app.Ux;
            data.Uy = app.Uy;
            data.Umag = app.Umag;
            
            data.Ex = app.Ex;
            data.Ey = app.Ey;
            data.Gxy = app.Gxy;
            
            data.Sx = app.Sx;
            data.Sy = app.Sy;
            data.Sxy = app.Sxy;
            
            if isempty(app.X) || isempty(app.Y) || isempty(app.ncon)
                error('No mesh available. Generate the mesh first.');
            end
            
            if isempty(app.U) || isempty(app.Sx)
                error('No FEM results available. Press Solve first.');
            end

            selectedValue = app.IsolinesDropDown.Value;
            
            plotSelectedContour( ...
                app.UIAxes5_2, ...
                selectedValue, ...
                data);

        
        end

        % Button pushed function: LoadANSYSMeshButton
        function LoadANSYSMeshButtonPushed(app, event)
        %Read the spreadsheet
        filename = 'ANSYSMESH';
        %filename = 'newansysmesh_converted';

        app.ANSYSmesh = true;
        data = readtable(filename);

        %Total COUNTS from row 1 (the only row with n_element/n_node)
        app.n_elements = data.n_element(1);   % 880
        app.n_nodes    = data.n_node(1);      % 496

        % Extracts the first n_elements rows of ncon columns
        app.ncon = int32([data.ncon1(1:app.n_elements), ...
                          data.ncon2(1:app.n_elements), ...
                          data.ncon3(1:app.n_elements)]);

        % Gets the node coordiantes first n_nodes rows of X/Y columns
        % Row i corresponds to node i (1-indexed), so no mapping needed
        app.X = data.X(1:app.n_nodes);
        app.Y = data.Y(1:app.n_nodes);

        %Material properties
        app.E = data.E(1);
        app.v = data.v(1);
        app.t = data.t(1);

       
        app.k_tool = 84;
        app.a2 = 0.7;
        %{
        %Forces
        if ismember('F', data.Properties.VariableNames)
            app.F = data.F(~isnan(data.F));
        end

        %Boundary conditions
        if ismember('dzero', data.Properties.VariableNames)
            app.dzero = data.dzero(~isnan(data.dzero));
        end
        if ismember('NDU', data.Properties.VariableNames)
            app.NDU = data.NDU(1);
        end
        %}
        %Checking for errors
        nconMax = double(max(app.ncon(:)));
        fprintf('Elements: %d | Nodes: %d | ncon range: [1, %d]\n', ...
            app.n_elements, app.n_nodes, nconMax);
        if nconMax > app.n_nodes
            warning('ncon max (%d) exceeds n_nodes (%d)!', nconMax, app.n_nodes);
        end

        %Plotting
        cla(app.UIAxes);
        hold(app.UIAxes, 'on');
        patch(app.UIAxes, ...
            'Faces',     app.ncon, ...
            'Vertices',  [app.X, app.Y], ...
            'FaceColor', 'none', ...
            'EdgeColor', [0 0 0]);
        axis(app.UIAxes, 'equal');
        title(app.UIAxes, 'ANSYS Mesh');
        xlabel(app.UIAxes, 'X');
        ylabel(app.UIAxes, 'Y');


        end

        % Button pushed function: ApplyButton_4
        function ApplyButton_4Pushed(app, event)
            %=====================BUTTON USED FOR THE APPLICATION OF
            %THERMAL CONSTRAINTS==========================
            app.Tambient = app.FixedTemperatureCEditField_2.Value;
            app.thermalContact = app.ToolTipContactLengthmEditField.Value;

            app.freeStreamT = app.FreeStreamTemperatureCEditField_2.Value;
            app.h = app.ConvectionCoefficienthEditField.Value;
            disp(app.h)
            disp(app.freeStreamT)


            if app.bevelled == true
            app.a2 = 0.8; 
            L = 0.0003;

            T_mean = mean_interface_temp(app.a2, L, app.Fc, app.Ft);
            end

            if app.normalMesh == true
            T_mean = mean_interface_temp(app.a2, app.Tc, app.Fc, app.Ft);
            end    
            
            if app.ANSYSmesh == true
            T_mean = mean_interface_temp(app.a2, app.Tc, app.Fc, app.Ft);    
            end    


            valueB = app.BottomEdgeCheckBox_2.Value;
            if valueB == 1

                app.Tbotedge=true;

            else

                app.Tbotedge=false;

            end


            valueR = app.RightChamferCheckBox_2.Value;
            if valueR == 1

                app.Trightcham=true;

            else

                app.Trightcham=false;

            end


            valueT = app.TopRightEdgeCheckBox.Value;
            if valueT == 1

                app.Ttopredge=true;

            else

                app.Ttopredge=false;

            end

            if isempty(app.X) || isempty(app.Y) || isempty(app.ncon)
                error('Mesh has not been generated. Go to Mesh Generator and click Apply first.');
            end


            [app.fixedNodes, app.fixedValues, app.thermalTipNodes] = ApplyThermalBoundary(app.thermalContact, ...
    app.Tbotedge, app.Trightcham, app.Ttopredge, ...
    app.n_nodes, app.X, app.Y, ...
    app.Xb, app.Yb, ...
    app.X_body, app.Y_body, ...
    app.X_tri, app.Y_tri, ...
    app.bevelled, app.ANSYSmesh, app.normalMesh, ...
    app.Tambient, T_mean);


% =========================================================
% STEP 2 - Define convection segments (bevelled case)
% =========================================================
if app.bevelled == true
segments = [
    app.X_body(1), app.Y_body(1), app.X_body(7), app.Y_body(7);  % top free surface
    app.X_body(2), app.Y_body(2), app.X_body(3), app.Y_body(3);  % left body chamfer
    app.X_tri(1),  app.Y_tri(1),  app.X_tri(2),  app.Y_tri(2);   % left tri chamfer
];

% =========================================================
% STEP 3 - Find convection edges
% =========================================================
app.convEdges = FindConvectionEdges(app.ncon, app.X, app.Y, segments);

% Remove any edges that touch a Dirichlet node
app.convEdges = app.convEdges(~ismember(app.convEdges(:,1), app.fixedNodes) & ...
                              ~ismember(app.convEdges(:,2), app.fixedNodes), :);
end


if app.normalMesh == true
segments = [
    app.Xb(1), app.Yb(1), app.Xb(4), app.Yb(4);  % top free surface
    app.Xb(1), app.Yb(1), app.Xb(2), app.Yb(2);  % left body chamfer
];

% =========================================================
% STEP 3 - Find convection edges
% =========================================================
app.convEdges = FindConvectionEdges(app.ncon, app.X, app.Y, segments);

% Remove any edges that touch a Dirichlet node
app.convEdges = app.convEdges(~ismember(app.convEdges(:,1), app.fixedNodes) & ...
                              ~ismember(app.convEdges(:,2), app.fixedNodes), :);
end


if app.ANSYSmesh == true

end    
  

if app.bevelled == true
    ambientNodes = setdiff(app.fixedNodes, app.thermalTipNodes);
    %{
            plot(app.UIAxes6, app.X_body, app.Y_body);
            hold(app.UIAxes6,'on')
            plot(app.UIAxes6, app.X_tri, app.Y_tri)

            % Plot clamped nodes
            scatter(app.UIAxes6, ...
                app.X(app.thermalTipNodes), ...
                app.Y(app.thermalTipNodes), ...
                70, 'filled', 'r')
            
            % Plot contact nodes
            %
            scatter(app.UIAxes6, ...
                app.X(ambientNodes), ...
                app.Y(ambientNodes), ...
                70, 'filled', 'b')
            axis(app.UIAxes6, 'equal');
            hold(app.UIAxes6,'off')
    %}

    %}
%{

plot(app.UIAxes6, app.X_body, app.Y_body);
hold(app.UIAxes6,'on')
plot(app.UIAxes6, app.X_tri, app.Y_tri)

% Plot contact (thermal tip) nodes
h1 = scatter(app.UIAxes6, ...
    app.X(app.thermalTipNodes), ...
    app.Y(app.thermalTipNodes), ...
    70, 'filled', 'r');

% Plot ambient fixed nodes
h2 = scatter(app.UIAxes6, ...
    app.X(ambientNodes), ...
    app.Y(ambientNodes), ...
    70, 'filled', 'b');

axis(app.UIAxes6, 'equal');

legend(app.UIAxes6, ...
    [h1 h2], ...
    {'Contact Temperature Nodes', 'Ambient Boundary Nodes'});

hold(app.UIAxes6,'off')
%}
        ambientNodes = setdiff(app.fixedNodes, app.thermalTipNodes);
    convNodes    = unique(app.convEdges(:));

    plot(app.UIAxes6, app.X_body, app.Y_body);
    hold(app.UIAxes6, 'on')
    plot(app.UIAxes6, app.X_tri, app.Y_tri)

    h1 = scatter(app.UIAxes6, app.X(app.thermalTipNodes), app.Y(app.thermalTipNodes), 70, 'filled', 'r');
    h2 = scatter(app.UIAxes6, app.X(ambientNodes),        app.Y(ambientNodes),        70, 'filled', 'b');
    h3 = scatter(app.UIAxes6, app.X(convNodes),           app.Y(convNodes),           70, 'filled', 'g');

    legend(app.UIAxes6, [h1 h2 h3], {'Contact Temperature', 'Ambient Fixed', 'Convection'});
    axis(app.UIAxes6, 'equal');
    hold(app.UIAxes6, 'off')
end

if app.ANSYSmesh == true
ambientNodes = setdiff(app.fixedNodes, app.thermalTipNodes);
plot(app.UIAxes6, app.X, app.Y);
hold(app.UIAxes6,'on')

% Plot contact (thermal tip) nodes
h1 = scatter(app.UIAxes6, ...
    app.X(app.thermalTipNodes), ...
    app.Y(app.thermalTipNodes), ...
    70, 'filled', 'r');

% Plot ambient fixed nodes
h2 = scatter(app.UIAxes6, ...
    app.X(ambientNodes), ...
    app.Y(ambientNodes), ...
    70, 'filled', 'b');

axis(app.UIAxes6, 'equal');

legend(app.UIAxes6, ...
    [h1 h2], ...
    {'Contact Temperature Nodes', 'Ambient Boundary Nodes'});

hold(app.UIAxes6,'off')
end


if app.normalMesh == true
    %{
ambientNodes = setdiff(app.fixedNodes, app.thermalTipNodes);
plot(app.UIAxes6, app.Xb, app.Yb);
hold(app.UIAxes6,'on')

% Plot contact (thermal tip) nodes
h1 = scatter(app.UIAxes6, ...
    app.X(app.thermalTipNodes), ...
    app.Y(app.thermalTipNodes), ...
    70, 'filled', 'r');

% Plot ambient fixed nodes
h2 = scatter(app.UIAxes6, ...
    app.X(ambientNodes), ...
    app.Y(ambientNodes), ...
    70, 'filled', 'b');

axis(app.UIAxes6, 'equal');

legend(app.UIAxes6, ...
    [h1 h2], ...
    {'Contact Temperature Nodes', 'Ambient Boundary Nodes'});

hold(app.UIAxes6,'off')
    %}


            ambientNodes = setdiff(app.fixedNodes, app.thermalTipNodes);
    convNodes    = unique(app.convEdges(:));

    plot(app.UIAxes6, app.Xb, app.Yb);
    hold(app.UIAxes6, 'on')


    h1 = scatter(app.UIAxes6, app.X(app.thermalTipNodes), app.Y(app.thermalTipNodes), 70, 'filled', 'r');
    h2 = scatter(app.UIAxes6, app.X(ambientNodes),        app.Y(ambientNodes),        70, 'filled', 'b');
    h3 = scatter(app.UIAxes6, app.X(convNodes),           app.Y(convNodes),           70, 'filled', 'g');

    legend(app.UIAxes6, [h1 h2 h3], {'Contact Temperature', 'Ambient Fixed', 'Convection'});
    axis(app.UIAxes6, 'equal');
    hold(app.UIAxes6, 'off')
end


        end

        % Button pushed function: ResetButton_4
        function ResetButton_4Pushed(app, event)
             %=====================BUTTON USED FOR THE APPLICATION OF
            %RESETTING THE THERMAL CONSTRAINTS============================
            
            app.fixedNodes = [];
            app.fixedValues = [];
            app.thermalTipNodes = [];
            cla(app.UIAxes6)

        end

        % Button pushed function: PlotandApplyButton
        function PlotandApplyButtonPushed(app, event)

        
        leftside = app.LeftSideDropDown.Value;
        rightside = app.RightSideDropDown.Value;
        topside = app.TopSideDropDown.Value;
        bottomside = app.BottomSideDropDown.Value;


        % Clear previous BCs
        fixedTempNow = app.FixedTemperatureCEditField.Value;


        app.fixedNodes      = [];
        app.fixedValues     = [];
        app.insulatedNodes  = [];
        app.convectionNodes = [];
    
        sides     = {'bottom', 'top', 'left', 'right'};
        dropdowns = {bottomside, ...
                     topside, ...
                     leftside, ...
                     rightside};
    
        for i = 1:4
            [fn, fv, in, cn] = applyBoundaryConditionGeneral( ...
                sides{i},    dropdowns{i}, ...
                app.divX,    app.divY, ...
                fixedTempNow);
    
            % Accumulate across all four sides
            app.fixedNodes      = [app.fixedNodes;      fn];
            app.fixedValues     = [app.fixedValues;     fv];
            app.insulatedNodes  = [app.insulatedNodes;  in];
            app.convectionNodes = [app.convectionNodes; cn];
        end
    
        % Remove duplicates (e.g. shared corner nodes)
        [app.fixedNodes, ia] = unique(app.fixedNodes, 'last');
        app.fixedValues      = app.fixedValues(ia);
        app.insulatedNodes   = unique(app.insulatedNodes);
        app.convectionNodes  = unique(app.convectionNodes);
    
        disp('Boundary conditions applied:');
        fprintf('  Fixed temp nodes:  %d\n', length(app.fixedNodes));
        fprintf('  Insulated nodes:   %d\n', length(app.insulatedNodes));
        fprintf('  Convection nodes:  %d\n', length(app.convectionNodes));

% Plot mesh first
cla(app.UIAxes7);
hold(app.UIAxes7, 'on');
patch(app.UIAxes7, ...
    'Faces',    app.ncong, ...
    'Vertices', [app.Xg(:), app.Yg(:)], ...
    'FaceColor','none', ...
    'EdgeColor',[0.7 0.7 0.7]);

% Fixed temperature nodes — red
if ~isempty(app.fixedNodes)
    scatter(app.UIAxes7, ...
        app.Xg(app.fixedNodes), app.Yg(app.fixedNodes), ...
        40, 'r', 'filled', 'DisplayName', 'Fixed Temp');
end

% Insulated nodes — blue
if ~isempty(app.insulatedNodes)
    scatter(app.UIAxes7, ...
        app.Xg(app.insulatedNodes), app.Yg(app.insulatedNodes), ...
        40, 'b', 'filled', 'DisplayName', 'Insulated');
end

% Convection nodes — green
if ~isempty(app.convectionNodes)
    scatter(app.UIAxes7, ...
        app.Xg(app.convectionNodes), app.Yg(app.convectionNodes), ...
        40, 'g', 'filled', 'DisplayName', 'Convection');
end

legend(app.UIAxes7, 'show', 'Location', 'best');
axis(app.UIAxes7, 'equal');
title(app.UIAxes7, 'Mesh with Boundary Conditions');
xlabel(app.UIAxes7, 'X');
ylabel(app.UIAxes7, 'Y');
hold(app.UIAxes7, 'off');
       
        end

        % Button pushed function: GenerateMeshButton
        function GenerateMeshButtonPushed(app, event)
        app.divX = app.DivisionsintheXEditField.Value;
        app.divY = app.DivisionsintheYEditField_2.Value;

        [app.Xg,app.Yg,app.ncong, app.n_nodesg, app.n_elementsg] = creategeneralmesh(app.X_g,app.Y_g, app.divX, app.divY);


cla(app.UIAxes7);
hold(app.UIAxes7, 'on');
patch(app.UIAxes7, ...
    'Faces',    app.ncong, ...
    'Vertices', [app.Xg(:), app.Yg(:)], ...
    'FaceColor','none', ...
    'EdgeColor',[0 0 0]);
axis(app.UIAxes7, 'equal');
title(app.UIAxes7,  'Mesh');
xlabel(app.UIAxes7, 'X');
ylabel(app.UIAxes7, 'Y');


        end

        % Button pushed function: SolveButton_2
        function SolveButton_2Pushed(app, event)
        app.fixedTemp = app.FixedTemperatureCEditField.Value;
        app.freeTemp = app.FreeStreamTemperatureCEditField.Value;
        app.uniformHeat = app.UniformHeatSourceQEditField.Value;
        app.convConst = app.ConvectionConstanthEditField.Value;
        app.conductConst = app.ConductivityConstantKxKyEditField.Value;
app.thickness = app.ThicknessmEditField_2.Value;
disp(app.thickness)
app.T_general = SolveThermalGeneral( ...
    app.n_nodesg, app.n_elementsg, ...
    app.ncong, app.Xg, app.Yg, ...
    app.thickness, ...                      % thickness t
    app.conductConst, app.conductConst, ...  % kx, ky
    app.uniformHeat, ...
    app.fixedNodes,  app.fixedValues, ...
    app.convectionNodes, app.convConst, app.freeTemp,app.divX,app.divY);


cla(app.UIAxes7);
hold(app.UIAxes7, 'on');

% Plot filled temperature contour over mesh
patch(app.UIAxes7, ...
    'Faces',           app.ncong, ...
    'Vertices',        [app.Xg(:), app.Yg(:)], ...
    'FaceVertexCData', app.T_general, ...
    'FaceColor',       'interp', ...
    'EdgeColor',       'none');

% Overlay mesh edges faintly
patch(app.UIAxes7, ...
    'Faces',     app.ncong, ...
    'Vertices',  [app.Xg(:), app.Yg(:)], ...
    'FaceColor', 'none', ...
    'EdgeColor', [0.5 0.5 0.5], ...
    'LineWidth', 0.3);

% Colourbar and formatting
colormap(app.UIAxes7, 'jet');
cb = colorbar(app.UIAxes7);
cb.Label.String = 'Temperature (°C)';
clim(app.UIAxes7, [min(app.T_general) max(app.T_general)]);

axis(app.UIAxes7, 'equal');
title(app.UIAxes7, 'Thermal Distribution');
xlabel(app.UIAxes7, 'X (m)');
ylabel(app.UIAxes7, 'Y (m)');
hold(app.UIAxes7, 'off');
legend(app.UIAxes7, 'off');  % remove legend

fprintf('T min: %.4f, max: %.4f, mean: %.4f\n', min(app.T_general), max(app.T_general), mean(app.T_general));
fprintf('Any NaN: %d, Any Inf: %d\n', any(isnan(app.T_general)), any(isinf(app.T_general)));

        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
        app.length = app.LengthmEditField.Value;
        app.width = app.WidthmEditField.Value;
            
        [app.X_g, app.Y_g] = creategeneralshape(app.length,app.width);
        disp(app.X_g)
        disp(app.Y_g)

        plot(app.UIAxes7, app.X_g, app.Y_g)
        axis(app.UIAxes7, 'equal')

       
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
               % =========================================================
    % CLEAR ALL STORED DATA
    % =========================================================
    app.X_g         = [];
    app.Y_g         = [];
    app.Xg          = [];
    app.Yg          = [];
    app.ncong       = [];
    app.n_nodesg    = 0;
    app.n_elementsg = 0;
    app.T_general   = [];

    % Geometry
    app.length    = 0;
    app.width     = 0;
    app.thickness = 0;
    app.divX      = 0;
    app.divY      = 0;

    % Thermal parameters
    app.fixedTemp    = 0;
    app.freeTemp     = 0;
    app.uniformHeat  = 0;
    app.convConst    = 0;
    app.conductConst = 0;

    % Boundary conditions
    app.fixedNodes      = [];
    app.fixedValues     = [];
    app.insulatedNodes  = [];
    app.convectionNodes = [];

    % =========================================================
    % RESET EDIT FIELDS
    % =========================================================
    app.LengthmEditField.Value                  = 0;
    app.WidthmEditField.Value                   = 0;
    app.ThicknessmEditField_2.Value               = 0;
    app.DivisionsintheXEditField.Value          = 0;
    app.DivisionsintheYEditField_2.Value        = 0;
    app.FixedTemperatureCEditField.Value        = 0;
    app.FreeStreamTemperatureCEditField.Value   = 0;
    app.UniformHeatSourceQEditField.Value       = 0;
    app.ConvectionConstanthEditField.Value      = 0;
    app.ConductivityConstantKxKyEditField.Value = 0;

    % =========================================================
    % RESET DROPDOWNS
    % =========================================================
    app.LeftSideDropDown.Value   = app.LeftSideDropDown.Items{1};
    app.RightSideDropDown.Value  = app.RightSideDropDown.Items{1};
    app.TopSideDropDown.Value    = app.TopSideDropDown.Items{1};
    app.BottomSideDropDown.Value = app.BottomSideDropDown.Items{1};

    % =========================================================
    % CLEAR AXES
    % =========================================================
    cla(app.UIAxes7);
    legend(app.UIAxes7, 'off');
    colorbar(app.UIAxes7, 'off');
    title(app.UIAxes7,  '');
    xlabel(app.UIAxes7, '');
    ylabel(app.UIAxes7, '');

  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ToolGeometryTab = uitab(app.TabGroup);
            app.ToolGeometryTab.Title = 'Tool Geometry';
            app.UIAxes3 = uiaxes(app.ToolGeometryTab);
            title(app.UIAxes3, 'Tool Geometry')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.TopWidthmEditFieldLabel = uilabel(app.ToolGeometryTab);
            app.TopWidthmEditFieldLabel.Text = 'Top Width (m)';
            app.TopWidthmEditField = uieditfield(app.ToolGeometryTab, 'numeric');
            app.BottomWidthmEditFieldLabel = uilabel(app.ToolGeometryTab);
            app.BottomWidthmEditFieldLabel.Text = 'Bottom Width (m)';
            app.BottomWidthmEditField = uieditfield(app.ToolGeometryTab, 'numeric');
            app.HeightmEditFieldLabel = uilabel(app.ToolGeometryTab);
            app.HeightmEditFieldLabel.Text = 'Height (m)';
            app.HeightmEditField = uieditfield(app.ToolGeometryTab, 'numeric');
            app.ThicknessmEditFieldLabel = uilabel(app.ToolGeometryTab);
            app.ThicknessmEditFieldLabel.Text = 'Thickness (m)';
            app.ThicknessmEditField = uieditfield(app.ToolGeometryTab, 'numeric');
            app.LoadBevelledToolButton = uibutton(app.ToolGeometryTab, 'push');
            app.LoadBevelledToolButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBevelledToolButtonPushed, true);
            app.LoadBevelledToolButton.Text = 'Load Bevelled Tool';
            app.ToolGeometryLabel = uilabel(app.ToolGeometryTab);
            app.ToolGeometryLabel.Text = 'Tool Geometry';
            app.MaterialPropertiesLabel = uilabel(app.ToolGeometryTab);
            app.MaterialPropertiesLabel.Text = 'Material Properties';
            app.MaterialDropDownLabel = uilabel(app.ToolGeometryTab);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.ToolGeometryTab);
            app.MaterialDropDown.Items = {'Tungsten Carbide', 'M2 High Speed Steel'};
            app.MaterialDropDown.Value = 'Tungsten Carbide';
            app.ResetButton = uibutton(app.ToolGeometryTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ApplyButton = uibutton(app.ToolGeometryTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.YoungsModulosGPaLabel = uilabel(app.ToolGeometryTab);
            app.YoungsModulosGPaLabel.Text = 'Youngs Modulos (GPa):';
            app.PoissonsRatioLabel = uilabel(app.ToolGeometryTab);
            app.PoissonsRatioLabel.Text = 'Poissons Ratio:';
            app.ToolContactTempLabel = uilabel(app.ToolGeometryTab);
            app.ToolContactTempLabel.Text = 'Tool Contact Temp: ';
            app.BevelledToolGeometryCheckBox = uicheckbox(app.ToolGeometryTab);
            app.BevelledToolGeometryCheckBox.ValueChangedFcn = createCallbackFcn(app, @BevelledToolGeometryCheckBoxValueChanged, true);
            app.BevelledToolGeometryCheckBox.Text = 'Bevelled Tool Geometry';
            app.StatusLampLabel = uilabel(app.ToolGeometryTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.ToolGeometryTab);
            app.MeshGeneratorTab = uitab(app.TabGroup);
            app.MeshGeneratorTab.Title = 'Mesh Generator';
            app.UIAxes = uiaxes(app.MeshGeneratorTab);
            title(app.UIAxes, 'Mesh')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MeshGeneratorLabel = uilabel(app.MeshGeneratorTab);
            app.MeshGeneratorLabel.Text = 'Mesh Generator';
            app.RefinedZonemEditFieldLabel = uilabel(app.MeshGeneratorTab);
            app.RefinedZonemEditFieldLabel.Text = 'Refined Zone (m)';
            app.RefinedZonemEditField = uieditfield(app.MeshGeneratorTab, 'numeric');
            app.DivisionsintherefinedzoneEditFieldLabel = uilabel(app.MeshGeneratorTab);
            app.DivisionsintherefinedzoneEditFieldLabel.Text = 'Divisions in the refined zone';
            app.DivisionsintherefinedzoneEditField = uieditfield(app.MeshGeneratorTab, 'numeric');
            app.DivisionsintherestEditFieldLabel = uilabel(app.MeshGeneratorTab);
            app.DivisionsintherestEditFieldLabel.Text = 'Divisions in the rest';
            app.DivisionsintherestEditField = uieditfield(app.MeshGeneratorTab, 'numeric');
            app.DivisionsintheYEditFieldLabel = uilabel(app.MeshGeneratorTab);
            app.DivisionsintheYEditFieldLabel.Text = 'Divisions in the Y';
            app.DivisionsintheYEditField = uieditfield(app.MeshGeneratorTab, 'numeric');
            app.ApplyButton_2 = uibutton(app.MeshGeneratorTab, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.ParametersalongtheXaxiasLabel = uilabel(app.MeshGeneratorTab);
            app.ParametersalongtheXaxiasLabel.Text = 'Parameters along the X axias';
            app.ParametersalongtheYaxisLabel = uilabel(app.MeshGeneratorTab);
            app.ParametersalongtheYaxisLabel.Text = 'Parameters along the Y axis';
            app.ResetButton_3 = uibutton(app.MeshGeneratorTab, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.LoadANSYSMeshButton = uibutton(app.MeshGeneratorTab, 'push');
            app.LoadANSYSMeshButton.ButtonPushedFcn = createCallbackFcn(app, @LoadANSYSMeshButtonPushed, true);
            app.LoadANSYSMeshButton.Text = 'Load ANSYS Mesh';
            app.LoadsandConstraintsTab = uitab(app.TabGroup);
            app.LoadsandConstraintsTab.Title = 'Loads and Constraints';
            app.UIAxes4 = uiaxes(app.LoadsandConstraintsTab);
            title(app.UIAxes4, 'Nodal Loads & Constraints')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.ApplyLoadsBoundaryConditionsLabel = uilabel(app.LoadsandConstraintsTab);
            app.ApplyLoadsBoundaryConditionsLabel.Text = 'Apply Loads & Boundary Conditions';
            app.BoundaryConstraintsLabel = uilabel(app.LoadsandConstraintsTab);
            app.BoundaryConstraintsLabel.Text = 'Boundary Constraints';
            app.AxialFeedForceNEditFieldLabel = uilabel(app.LoadsandConstraintsTab);
            app.AxialFeedForceNEditFieldLabel.Text = 'Axial Feed Force (N)';
            app.AxialFeedForceNEditField = uieditfield(app.LoadsandConstraintsTab, 'numeric');
            app.CuttingTangentialForceNEditFieldLabel = uilabel(app.LoadsandConstraintsTab);
            app.CuttingTangentialForceNEditFieldLabel.Text = 'Cutting Tangential Force (N)';
            app.CuttingTangentialForceNEditField = uieditfield(app.LoadsandConstraintsTab, 'numeric');
            app.BottomEdgeCheckBox = uicheckbox(app.LoadsandConstraintsTab);
            app.BottomEdgeCheckBox.ValueChangedFcn = createCallbackFcn(app, @BottomEdgeCheckBoxValueChanged, true);
            app.BottomEdgeCheckBox.Text = 'Bottom Edge';
            app.RightChamferCheckBox = uicheckbox(app.LoadsandConstraintsTab);
            app.RightChamferCheckBox.ValueChangedFcn = createCallbackFcn(app, @RightChamferCheckBoxValueChanged, true);
            app.RightChamferCheckBox.Text = 'Right Chamfer';
            app.TopHalfRightEdgeCheckBox = uicheckbox(app.LoadsandConstraintsTab);
            app.TopHalfRightEdgeCheckBox.ValueChangedFcn = createCallbackFcn(app, @TopHalfRightEdgeCheckBoxValueChanged, true);
            app.TopHalfRightEdgeCheckBox.Text = 'Top Half Right Edge';
            app.ApplyButton_3 = uibutton(app.LoadsandConstraintsTab, 'push');
            app.ApplyButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_3Pushed, true);
            app.ApplyButton_3.Text = 'Apply';
            app.ForceContactLengthmEditFieldLabel = uilabel(app.LoadsandConstraintsTab);
            app.ForceContactLengthmEditFieldLabel.Text = 'Force Contact Length (m)';
            app.ForceContactLengthmEditField = uieditfield(app.LoadsandConstraintsTab, 'numeric');
            app.ResetButton_2 = uibutton(app.LoadsandConstraintsTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.Label = uilabel(app.LoadsandConstraintsTab);
            app.Label.Text = '';
            app.ThermalConstraintsTab = uitab(app.TabGroup);
            app.ThermalConstraintsTab.Title = 'Thermal Constraints';
            app.UIAxes6 = uiaxes(app.ThermalConstraintsTab);
            title(app.UIAxes6, 'Nodal Constraints')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.ThermalParametersLabel_2 = uilabel(app.ThermalConstraintsTab);
            app.ThermalParametersLabel_2.Text = 'Thermal Parameters';
            app.FixedTemperatureCEditField_2Label = uilabel(app.ThermalConstraintsTab);
            app.FixedTemperatureCEditField_2Label.Text = 'Fixed Temperature (*C)';
            app.FixedTemperatureCEditField_2 = uieditfield(app.ThermalConstraintsTab, 'numeric');
            app.BottomEdgeCheckBox_2 = uicheckbox(app.ThermalConstraintsTab);
            app.BottomEdgeCheckBox_2.Text = 'Bottom Edge';
            app.RightChamferCheckBox_2 = uicheckbox(app.ThermalConstraintsTab);
            app.RightChamferCheckBox_2.Text = 'Right Chamfer';
            app.TopRightEdgeCheckBox = uicheckbox(app.ThermalConstraintsTab);
            app.TopRightEdgeCheckBox.Text = 'Top Right Edge';
            app.FixedTemperatureConstraintsLabel = uilabel(app.ThermalConstraintsTab);
            app.FixedTemperatureConstraintsLabel.Text = 'Fixed Temperature Constraints';
            app.ThermalConstraintParametersLabel = uilabel(app.ThermalConstraintsTab);
            app.ThermalConstraintParametersLabel.Text = 'Thermal Constraint Parameters';
            app.ApplyButton_4 = uibutton(app.ThermalConstraintsTab, 'push');
            app.ApplyButton_4.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_4Pushed, true);
            app.ApplyButton_4.Text = 'Apply';
            app.ResetButton_4 = uibutton(app.ThermalConstraintsTab, 'push');
            app.ResetButton_4.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_4Pushed, true);
            app.ResetButton_4.Text = 'Reset';
            app.ToolTipContactLengthmEditFieldLabel = uilabel(app.ThermalConstraintsTab);
            app.ToolTipContactLengthmEditFieldLabel.Text = 'Tool Tip Contact Length (m)';
            app.ToolTipContactLengthmEditField = uieditfield(app.ThermalConstraintsTab, 'numeric');
            app.FreeStreamTemperatureCEditField_2Label = uilabel(app.ThermalConstraintsTab);
            app.FreeStreamTemperatureCEditField_2Label.Text = 'Free Stream Temperature (*C)';
            app.FreeStreamTemperatureCEditField_2 = uieditfield(app.ThermalConstraintsTab, 'numeric');
            app.ConvectionCoefficienthEditFieldLabel = uilabel(app.ThermalConstraintsTab);
            app.ConvectionCoefficienthEditFieldLabel.Text = 'Convection Coefficient (h)';
            app.ConvectionCoefficienthEditField = uieditfield(app.ThermalConstraintsTab, 'numeric');
            app.SolveTab = uitab(app.TabGroup);
            app.SolveTab.Title = 'Solve';
            app.UIAxes5 = uiaxes(app.SolveTab);
            title(app.UIAxes5, 'Elostatic Analysis')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5_2 = uiaxes(app.SolveTab);
            title(app.UIAxes5_2, 'Thermal Analysis')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            zlabel(app.UIAxes5_2, 'Z')
            app.IsolinesDropDownLabel = uilabel(app.SolveTab);
            app.IsolinesDropDownLabel.Text = 'Isolines';
            app.IsolinesDropDown = uidropdown(app.SolveTab);
            app.IsolinesDropDown.Items = {'Sx', 'Sy', 'Sxy', 'Ux', 'Uy', 'Uxy', 'Ex', 'Ey', 'Exy'};
            app.IsolinesDropDown.Value = 'Sx';
            app.PatchDropDownLabel = uilabel(app.SolveTab);
            app.PatchDropDownLabel.Text = 'Patch';
            app.PatchDropDown = uidropdown(app.SolveTab);
            app.PatchDropDown.Items = {'Sx', 'Sy', 'Sxy', 'Ux', 'Uy', 'Uxy', 'Ex', 'Ey', 'Exy', 'Temperature'};
            app.PatchDropDown.Value = 'Sx';
            app.SolveButton = uibutton(app.SolveTab, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.ContoursLabel = uilabel(app.SolveTab);
            app.ContoursLabel.Text = 'Contours';
            app.ScaleEditFieldLabel = uilabel(app.SolveTab);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.SolveTab, 'numeric');
            app.PlotButton = uibutton(app.SolveTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlotButton_2 = uibutton(app.SolveTab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.ThermaldistributioncanbeplottedunderneaththepatchdropdownLabel = uilabel(app.SolveTab);
            app.ThermaldistributioncanbeplottedunderneaththepatchdropdownLabel.Text = {'Thermal distribution can be plotted underneath the patch '; 'dropdown****'};
            app.SolveforfullanalysisLabel = uilabel(app.SolveTab);
            app.SolveforfullanalysisLabel.Text = 'Solve for full analysis';
            app.SolveMenuLabel = uilabel(app.SolveTab);
            app.SolveMenuLabel.Text = 'Solve Menu';
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.UIAxes7 = uiaxes(app.GeneralTab);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.WidthmEditFieldLabel = uilabel(app.GeneralTab);
            app.WidthmEditFieldLabel.Text = 'Width (m)';
            app.WidthmEditField = uieditfield(app.GeneralTab, 'numeric');
            app.GeometryLabel = uilabel(app.GeneralTab);
            app.GeometryLabel.Text = 'Geometry';
            app.LengthmEditFieldLabel = uilabel(app.GeneralTab);
            app.LengthmEditFieldLabel.Text = 'Length (m)';
            app.LengthmEditField = uieditfield(app.GeneralTab, 'numeric');
            app.BoundaryConstraintsLabel_3 = uilabel(app.GeneralTab);
            app.BoundaryConstraintsLabel_3.Text = 'Boundary Constraints';
            app.LeftSideDropDownLabel = uilabel(app.GeneralTab);
            app.LeftSideDropDownLabel.Text = 'Left Side';
            app.LeftSideDropDown = uidropdown(app.GeneralTab);
            app.LeftSideDropDown.Items = {'Fixed Temperature', 'Convection', 'Insulated'};
            app.LeftSideDropDown.Value = 'Fixed Temperature';
            app.RightSideDropDownLabel = uilabel(app.GeneralTab);
            app.RightSideDropDownLabel.Text = 'Right Side';
            app.RightSideDropDown = uidropdown(app.GeneralTab);
            app.RightSideDropDown.Items = {'Fixed Temperature', 'Convection', 'Insulated'};
            app.RightSideDropDown.Value = 'Fixed Temperature';
            app.TopSideDropDownLabel = uilabel(app.GeneralTab);
            app.TopSideDropDownLabel.Text = 'Top Side';
            app.TopSideDropDown = uidropdown(app.GeneralTab);
            app.TopSideDropDown.Items = {'Fixed Temperature', 'Convection', 'Insulated'};
            app.TopSideDropDown.Value = 'Fixed Temperature';
            app.BottomSideDropDownLabel = uilabel(app.GeneralTab);
            app.BottomSideDropDownLabel.Text = 'Bottom Side';
            app.BottomSideDropDown = uidropdown(app.GeneralTab);
            app.BottomSideDropDown.Items = {'Fixed Temperature', 'Convection', 'Insulated'};
            app.BottomSideDropDown.Value = 'Fixed Temperature';
            app.ThicknessmEditField_2Label = uilabel(app.GeneralTab);
            app.ThicknessmEditField_2Label.Text = 'Thickness (m)';
            app.ThicknessmEditField_2 = uieditfield(app.GeneralTab, 'numeric');
            app.ThicknessmEditField_2.Value = 0.001;
            app.ThermalParametersLabel = uilabel(app.GeneralTab);
            app.ThermalParametersLabel.Text = 'Thermal Parameters';
            app.FixedTemperatureCEditFieldLabel = uilabel(app.GeneralTab);
            app.FixedTemperatureCEditFieldLabel.Text = 'Fixed Temperature (*C)';
            app.FixedTemperatureCEditField = uieditfield(app.GeneralTab, 'numeric');
            app.FreeStreamTemperatureCEditFieldLabel = uilabel(app.GeneralTab);
            app.FreeStreamTemperatureCEditFieldLabel.Text = 'Free Stream Temperature (*C)';
            app.FreeStreamTemperatureCEditField = uieditfield(app.GeneralTab, 'numeric');
            app.UniformHeatSourceQEditFieldLabel = uilabel(app.GeneralTab);
            app.UniformHeatSourceQEditFieldLabel.Text = 'Uniform Heat Source (Q)';
            app.UniformHeatSourceQEditField = uieditfield(app.GeneralTab, 'numeric');
            app.ConvectionConstanthEditFieldLabel = uilabel(app.GeneralTab);
            app.ConvectionConstanthEditFieldLabel.Text = 'Convection Constant (h)';
            app.ConvectionConstanthEditField = uieditfield(app.GeneralTab, 'numeric');
            app.ConductivityConstantKxKyEditFieldLabel = uilabel(app.GeneralTab);
            app.ConductivityConstantKxKyEditFieldLabel.Text = 'Conductivity Constant (Kx, Ky)';
            app.ConductivityConstantKxKyEditField = uieditfield(app.GeneralTab, 'numeric');
            app.MeshGeneratorLabel_2 = uilabel(app.GeneralTab);
            app.MeshGeneratorLabel_2.Text = 'Mesh Generator';
            app.DivisionsintheXEditFieldLabel = uilabel(app.GeneralTab);
            app.DivisionsintheXEditFieldLabel.Text = 'Divisions in the X';
            app.DivisionsintheXEditField = uieditfield(app.GeneralTab, 'numeric');
            app.DivisionsintheYEditField_2Label = uilabel(app.GeneralTab);
            app.DivisionsintheYEditField_2Label.Text = 'Divisions in the Y';
            app.DivisionsintheYEditField_2 = uieditfield(app.GeneralTab, 'numeric');
            app.PlotandApplyButton = uibutton(app.GeneralTab, 'push');
            app.PlotandApplyButton.ButtonPushedFcn = createCallbackFcn(app, @PlotandApplyButtonPushed, true);
            app.PlotandApplyButton.Text = 'Plot and Apply';
            app.GenerateMeshButton = uibutton(app.GeneralTab, 'push');
            app.GenerateMeshButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMeshButtonPushed, true);
            app.GenerateMeshButton.Text = 'Generate Mesh';
            app.SolveButton_2 = uibutton(app.GeneralTab, 'push');
            app.SolveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SolveButton_2Pushed, true);
            app.SolveButton_2.Text = 'Solve';
            app.GeneralThermalFieldsSolverLabel = uilabel(app.GeneralTab);
            app.GeneralThermalFieldsSolverLabel.Text = 'General Thermal Fields Solver';
            app.PlotButton_3 = uibutton(app.GeneralTab, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.RESETButton = uibutton(app.GeneralTab, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thermal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end